classdef AppDesignerRGB_Detect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        PercentErrorB  matlab.ui.control.Label
        ErrorB         matlab.ui.control.Label
        PercentErrorG  matlab.ui.control.Label
        ErrorG         matlab.ui.control.Label
        PercentErrorR  matlab.ui.control.Label
        ErrorR         matlab.ui.control.Label
        anguloData     matlab.ui.control.Label
        nguloLabel     matlab.ui.control.Label
        InicioButton   matlab.ui.control.Button
        UIAxesB        matlab.ui.control.UIAxes
        UIAxesG        matlab.ui.control.UIAxes
        UIAxesR        matlab.ui.control.UIAxes
        UIAxesVid      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)


            vid=videoinput('winvideo',1,'MJPG_1280x720');

set(vid,'FramesPerTrigger',Inf);
%
set(vid,'ReturnedColorspace','rgb');
vid.FrameGrabInterval=5;
  counterBR=0;
  counterGR=0;
  counterBG=0;
  counterGG=0;
  counterBB=0;
  counterGB=0;
  
%start(vid);

while(vid.FramesAcquired<=200)
    data=getsnapshot(vid);
BR=[];
BG=[];
BB=[];

    imshow(data,'Parent',app.UIAxesVid);
        drawnow;
    %image(app.UIAxesVid,data);


    diff_im1=imsubtract(data(:,:,1),rgb2gray(data));
    diff_im1=medfilt2(diff_im1,[3,3]);
    diff_im1=im2bw(diff_im1,0.18);
    diff_im1=bwareaopen(diff_im1,300);
    bw1=bwlabel(diff_im1,8);
    imshow(bw1,'Parent',app.UIAxesR);
    % image(app.,bw1);
    %imshow(bw1)
    stats1=regionprops(bw1,'BoundingBox','Centroid');

      BR = bwboundaries(bw1);
          
% isempty(B)
if(isempty(BR)==1)
    counterBR=counterBR+1;
    %counterBR+1
else 
        counterGR=counterGR+1;
end
 app.ErrorR.Text=num2str(counterBR);
  app.PercentErrorR.Text=num2str((100/(counterBR+counterGR))*counterBR);

    diff_im2=imsubtract(data(:,:,2),rgb2gray(data));
    diff_im2=medfilt2(diff_im2,[3,3]);
    diff_im2=im2bw(diff_im2,0.18);
    diff_im2=bwareaopen(diff_im2,300);
    bw2=bwlabel(diff_im2,8);
    imshow(bw2,'Parent',app.UIAxesG);
   % image(app.UIAxesG,bw2);
    stats2=regionprops(bw2,'BoundingBox','Centroid');

      BG = bwboundaries(bw1);
          
% isempty(B)
if(isempty(BG)==1)
    counterBG=counterBG+1;
    %counterBR+1
else 
        counterGG=counterGG+1;
end
 app.ErrorG.Text=num2str(counterBG);
  app.PercentErrorG.Text=num2str((100/(counterBG+counterGG))*counterBG);

diff_im3=imsubtract(data(:,:,3),rgb2gray(data));
    diff_im3=medfilt2(diff_im3,[3,3]);
    diff_im3=im2bw(diff_im3,0.18);
    diff_im3=bwareaopen(diff_im3,300);
    bw3=bwlabel(diff_im3,8);
    imshow(bw3,'Parent',app.UIAxesB);
   % image(app.UIAxesB,bw3);
    stats3=regionprops(bw3,'BoundingBox','Centroid');

      BB = bwboundaries(bw1);
          
% isempty(B)
if(isempty(BB)==1)
    counterBB=counterBB+1;
    %counterBR+1
else 
        counterGB=counterGB+1;
end
 app.ErrorB.Text=num2str(counterBB);
  app.PercentErrorB.Text=num2str((100/(counterBB+counterGB))*counterBB);
    % imshow(data);

    hold (app.UIAxesVid, 'on')

    for(object1=1:length(stats1))
bb1=stats1(object1).BoundingBox;
bc1=stats1(object1).Centroid;
rectangle(app.UIAxesVid,'Position',bb1,'EdgeColor','r','LineWidth',2)
plot(app.UIAxesVid,bc1(1),bc1(2),'-m+')
    drawnow;
a1=text(app.UIAxesVid,bc1(1)+15,bc1(2), strcat('X : ', num2str(round(bc1(1))), '   Y : ', num2str(round(bc1(2)))));
           set(a1, 'FontName', 'Arial', 'FontWeight', 'bold', 'FontSize', 12, 'Color', 'yellow');

% imshow(bw1)

    end


    for(object2=1:length(stats2))
bb2=stats2(object2).BoundingBox;
bc2=stats2(object2).Centroid;
rectangle(app.UIAxesVid,'Position',bb2,'EdgeColor','r','LineWidth',2)
plot(app.UIAxesVid,bc2(1),bc2(2),'-m+')
a2=text(app.UIAxesVid,bc2(1)+15,bc2(2), strcat('X : ', num2str(round(bc2(1))), '   Y : ', num2str(round(bc2(2)))));
           set(a2, 'FontName', 'Arial', 'FontWeight', 'bold', 'FontSize', 12, 'Color', 'yellow');

%imshow(bw2)

    end

     for(object3=1:length(stats3))
bb3=stats3(object3).BoundingBox;
bc3=stats3(object3).Centroid;
rectangle(app.UIAxesVid,'Position',bb3,'EdgeColor','r','LineWidth',2)
plot(app.UIAxesVid,bc3(1),bc3(2),'-m+')
    drawnow;
a3=text(app.UIAxesVid,bc3(1)+15,bc3(2), strcat('X : ', num2str(round(bc3(1))), '   Y : ', num2str(round(bc3(2)))));
           set(a3, 'FontName', 'Arial', 'FontWeight', 'bold', 'FontSize', 12, 'Color', 'yellow');

      %   imshow(bw3)

     end

  app.anguloData.Text="Colóquese correctamente los marcadores";


if((isempty(BR)==1)&&(isempty(BG)==1)&&(isempty(BB)==1))
  
app.anguloData.Text="Colóquese correctamente los marcadores";

 else
     

ab1 = polyfit([bc1(1),bc1(2)],[bc3(1),bc3(2)],1);
ab2 = polyfit([bc1(1),bc1(2)],[bc2(1),bc2(2)],1);


%V1=bc1:bc3
%V2=bc1:bc2

vect1 = [1 ab1(1) 1]; % Create a vector based on the line equation
vect2 = [1 ab2(1) 1];

dp = dot(vect1, vect2);
%Compute vector lengths

length1 = sqrt(sum(vect1.^2));
length2 = sqrt(sum(vect2.^2));
%Obtain the larger angle of intersection in degrees

angle = atan2d(norm(cross(vect1,vect2)),dot(vect1,vect2));
  app.anguloData.Text=num2str(angle);


if((isempty(BR)==1)&&(isempty(BG)==1)&&(isempty(BB)==1))
   
else 
     plot(app.UIAxesVid,[bc1(1),bc3(1)],[bc1(2),bc3(2)],"g",LineWidth=2);
 drawnow;
 plot(app.UIAxesVid,[bc1(1),bc2(1)],[bc1(2),bc2(2)],"g",LineWidth=2);
drawnow;


end


% intersection = [1 ,-ab1(1); 1, -ab2(1)] \ [ab1(2); ab2(2)];
% % intersection = bc1;
% % inter_x = bc1(1);
% % inter_y = bc1(2);
% % plot(app.UIAxesVid,inter_x,inter_y,"yx","LineWidth",2);
% %     drawnow;
% % angleString = [sprintf("%1.3f",angle)+"{\circ}"];
% % text(inter_x-80,inter_y-25,angleString, ...
% %      Color="y",FontSize=14,FontWeight="bold");

% intersectionString = sprintf("(%2.1f,%2.1f)",inter_x,inter_y);
% text(inter_x-10,inter_y+20,intersectionString,...
%      Color="y",FontSize=14,FontWeight="bold");

% plot(bc1,bc3,"g",LineWidth=2);
% plot(bc3,bc2,"g",LineWidth=2);
    clo(app.UIAxesVid,a1);
    clo(app.UIAxesVid,a2);
    clo(app.UIAxesVid,a3);


end

end
% 
% cla(app.UIAxesVid)


    stop(vid);
    flushdata(vid);
    clear all;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesVid = uiaxes(app.UIFigure);
            title(app.UIAxesVid, 'Análisis a Tiempo real')
            app.UIAxesVid.XTick = [];
            app.UIAxesVid.XTickLabel = '';
            app.UIAxesVid.YTick = [];
            app.UIAxesR = uiaxes(app.UIFigure);
            title(app.UIAxesR, 'Red Marker')
            app.UIAxesR.XTick = [];
            app.UIAxesR.YTick = [];
            app.UIAxesG = uiaxes(app.UIFigure);
            title(app.UIAxesG, 'Green Marker')
            app.UIAxesG.XTick = [];
            app.UIAxesG.XTickLabel = '';
            app.UIAxesG.YTick = [];
            app.UIAxesB = uiaxes(app.UIFigure);
            title(app.UIAxesB, 'Blue Marker')
            app.UIAxesB.XTick = [];
            app.UIAxesB.XTickLabel = '';
            app.UIAxesB.YTick = [];
            app.UIAxesB.YTickLabel = '';
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.nguloLabel = uilabel(app.UIFigure);
            app.nguloLabel.Text = 'Ángulo:';
            app.anguloData = uilabel(app.UIFigure);
            app.anguloData.Text = '0';
            app.ErrorR = uilabel(app.UIFigure);
            app.ErrorR.Text = '';
            app.PercentErrorR = uilabel(app.UIFigure);
            app.PercentErrorR.Text = '';
            app.ErrorG = uilabel(app.UIFigure);
            app.ErrorG.Text = '';
            app.PercentErrorG = uilabel(app.UIFigure);
            app.PercentErrorG.Text = '';
            app.ErrorB = uilabel(app.UIFigure);
            app.ErrorB.Text = '';
            app.PercentErrorB = uilabel(app.UIFigure);
            app.PercentErrorB.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppDesignerRGB_Detect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end