classdef AppDesigner_ProcesadoDeImagen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RGB_Detect                      matlab.ui.container.Tab
        NErroresErrorLabel_3            matlab.ui.control.Label
        NErroresErrorLabel_2            matlab.ui.control.Label
        NErroresErrorLabel              matlab.ui.control.Label
        InicioButton                    matlab.ui.control.Button
        anguloData                      matlab.ui.control.Label
        nguloLabel                      matlab.ui.control.Label
        ErrorR                          matlab.ui.control.Label
        PercentErrorR                   matlab.ui.control.Label
        ErrorG                          matlab.ui.control.Label
        PercentErrorG                   matlab.ui.control.Label
        ErrorB                          matlab.ui.control.Label
        PercentErrorB                   matlab.ui.control.Label
        UIAxesG                         matlab.ui.control.UIAxes
        UIAxesB                         matlab.ui.control.UIAxes
        UIAxesR                         matlab.ui.control.UIAxes
        UIAxesVid                       matlab.ui.control.UIAxes
        AnlisisBiomecnicoTab            matlab.ui.container.Tab
        nguloMaximoEditField            matlab.ui.control.NumericEditField
        nguloMaximoEditFieldLabel       matlab.ui.control.Label
        VelocidadAngularSubidaradsEditField  matlab.ui.control.NumericEditField
        VelocidadAngularSubidaradsEditFieldLabel  matlab.ui.control.Label
        VelocidadAngularBajadaradsEditField  matlab.ui.control.NumericEditField
        VelocidadAngularBajadaradsEditFieldLabel  matlab.ui.control.Label
        TiempoTotalsEditField           matlab.ui.control.NumericEditField
        TiempoTotalsEditFieldLabel      matlab.ui.control.Label
        TiempoBajarsEditField           matlab.ui.control.NumericEditField
        TiempoBajarsEditFieldLabel      matlab.ui.control.Label
        TiempoSubirsEditField           matlab.ui.control.NumericEditField
        TiempoSubirsEditFieldLabel      matlab.ui.control.Label
        nguloFinalEditField             matlab.ui.control.NumericEditField
        nguloFinalEditFieldLabel        matlab.ui.control.Label
        nguloInicialEditField           matlab.ui.control.NumericEditField
        nguloInicialEditFieldLabel      matlab.ui.control.Label
        SealrealizadaporelpacienteLamp  matlab.ui.control.Lamp
        SealrealizadaporelpacienteLampLabel  matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        pinta
        valor
         curva
        curva10k
        eje10s
        curvaVariable
        ejeAleatorioTiempo
        ejex
        ejex10k
        curvam10k
        curvanueva10k
        peso
        altura
        causa
        DNI
        apellidos
        nombre
 
     introduccion_datos_del_paciente
       torso
        brazof
        brazo
        mano
        brazo2
        brazof2
        mano2
        pierna
        pierna2
        gemelo
        gemelo2
        pies
        pies2
        gradoDeCalidad
        f
        x
        y
        C
        curva10ksup
        curva10kinf
     
        AngInic
       AngFin
       AngMax;
       Tiempo1_2
       Tiempo2_3
       TiempoTotal
       VelocAng1_2
       VelocAng2_3
       pasopintado
       pinta2
       pasopintado2
        curvafiltrada
 % Description
      %curvafiltrada=[70,28,10,80,28,10,80,12,10,70,12,10,70,28,30,80,28,30,80,12,30,70,12,30];
      tiempod=1;
    end

    methods (Access = private)
        
        function analisisBiomec(app)
            %Ángulos
%x=app.NombreDeLaCurva; 
%y=x(1);
% DATO ÁNGULO

app.AngInic=app.curvafiltrada(1);
app.AngFin=app.curvafiltrada(end); % DATO ANGULO

%app.AngInic=30;
%app.AngFin=30;

MuestraAngInic=0;
[app.AngMax,MuestraAngMax]=max(app.curvafiltrada); %DATO ÁNGULO, el otro nos hace falta para calcular pero no hace falta mostrarlo
MuestraAngFin=length(app.curvafiltrada);

%Tiempos
muestras=20;
t=30;
fs=muestras/t;


app.Tiempo1_2=((MuestraAngMax-MuestraAngInic)/fs); %DATO TIEMPO
app.Tiempo2_3=((MuestraAngFin-MuestraAngMax)/fs); %DATO TIEMPO
%app.TiempoTotal=((MuestraAngFin-MuestraAngInic)/fs); %DATO TIEMPO
app.TiempoTotal=(((MuestraAngMax-MuestraAngInic)/fs)+((MuestraAngFin-MuestraAngMax)/fs));


%Velocidad angular. En radianes que es lo correcto
%Convertimos de grados a radianes
RadAngMax=((app.AngMax*pi)/180);
RadAngInic=((app.AngInic*pi)/180);
RadAngFin=((app.AngFin*pi)/180);
app.VelocAng1_2=((RadAngMax-RadAngInic)/app.Tiempo1_2); %DATO VELOCIDAD ANGULAR
app.VelocAng2_3=((RadAngMax-RadAngFin)/app.Tiempo2_3); %DATO VELOCIDAD ANGULAR
   

          app.TiempoTotalsEditField.Value = 2;%app.tiempod+10;
          app.TiempoBajarsEditField.Value=app.Tiempo1_2;
          app.TiempoSubirsEditField.Value=app.Tiempo2_3;
          app.VelocidadAngularBajadaradsEditField.Value=app.VelocAng1_2;
          app.VelocidadAngularSubidaradsEditField.Value=app.VelocAng2_3;
          app.nguloFinalEditField.Value=app.AngFin;
          app.nguloInicialEditField.Value=app.AngInic;
          app.nguloMaximoEditField.Value=app.AngMax;
         

        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)


            vid=videoinput('winvideo',1,'MJPG_1280x720');

set(vid,'FramesPerTrigger',Inf);
%
set(vid,'ReturnedColorspace','rgb');
vid.FrameGrabInterval=5;
  counterBR=0;
  counterGR=0;
  counterBG=0;
  counterGG=0;
  counterBB=0;
  counterGB=0;
  
start(vid);
app.curvafiltrada=[];
a=0;

% ROBOT:--------------------------------------
% Descomentar solo si hay un arduino conectado
% ar = serial('COM4','BaudRate',9600);
% fopen(ar);
%Al final esta el fclose


while(vid.FramesAcquired<=300)
    data=getsnapshot(vid);
 
BR=[];
BG=[];
BB=[];
a=a+1;
    imshow(data,'Parent',app.UIAxesVid);
        drawnow;


    diff_im1=imsubtract(data(:,:,1),rgb2gray(data));
    diff_im1=medfilt2(diff_im1,[3,3]);
    diff_im1=im2bw(diff_im1,0.18);
    diff_im1=bwareaopen(diff_im1,300);
    bw1=bwlabel(diff_im1,8);
    imshow(bw1,'Parent',app.UIAxesR);

    stats1=regionprops(bw1,'BoundingBox','Centroid');

      BR = bwboundaries(bw1);
          

if(isempty(BR)==1)
    counterBR=counterBR+1;

else 
        counterGR=counterGR+1;
end
 app.ErrorR.Text=num2str(counterBR);
  app.PercentErrorR.Text=num2str((100/(counterBR+counterGR))*counterBR);

    diff_im2=imsubtract(data(:,:,2),rgb2gray(data));
    diff_im2=medfilt2(diff_im2,[3,3]);
    diff_im2=im2bw(diff_im2,0.18);
    diff_im2=bwareaopen(diff_im2,300);
    bw2=bwlabel(diff_im2,8);
    imshow(bw2,'Parent',app.UIAxesG);
    stats2=regionprops(bw2,'BoundingBox','Centroid');

      BG = bwboundaries(bw1);
          

if(isempty(BG)==1)
    counterBG=counterBG+1;

else 
        counterGG=counterGG+1;
end
 app.ErrorG.Text=num2str(counterBG);
  app.PercentErrorG.Text=num2str((100/(counterBG+counterGG))*counterBG);

diff_im3=imsubtract(data(:,:,3),rgb2gray(data));
    diff_im3=medfilt2(diff_im3,[3,3]);
    diff_im3=im2bw(diff_im3,0.18);
    diff_im3=bwareaopen(diff_im3,300);
    bw3=bwlabel(diff_im3,8);
    imshow(bw3,'Parent',app.UIAxesB);

    stats3=regionprops(bw3,'BoundingBox','Centroid');

      BB = bwboundaries(bw1);
          

if(isempty(BB)==1)
    counterBB=counterBB+1;

else 
        counterGB=counterGB+1;
end
 app.ErrorB.Text=num2str(counterBB);
  app.PercentErrorB.Text=num2str((100/(counterBB+counterGB))*counterBB);


    hold (app.UIAxesVid, 'on')

    for(object1=1:length(stats1))
bb1=stats1(object1).BoundingBox;
bc1=stats1(object1).Centroid;
rectangle(app.UIAxesVid,'Position',bb1,'EdgeColor','r','LineWidth',2)
plot(app.UIAxesVid,bc1(1),bc1(2),'-m+')
    drawnow;
%a1=text(app.UIAxesVid,bc1(1)+15,bc1(2), strcat('X : ', num2str(round(bc1(1))), '   Y : ', num2str(round(bc1(2)))));
        %   set(a1, 'FontName', 'Arial', 'FontWeight', 'bold', 'FontSize', 12, 'Color', 'yellow');

%Numeros comentados para ahorrar memoria
    end


    for(object2=1:length(stats2))
bb2=stats2(object2).BoundingBox;
bc2=stats2(object2).Centroid;
rectangle(app.UIAxesVid,'Position',bb2,'EdgeColor','r','LineWidth',2)
plot(app.UIAxesVid,bc2(1),bc2(2),'-m+')
drawnow;
%a2=text(app.UIAxesVid,bc2(1)+15,bc2(2), strcat('X : ', num2str(round(bc2(1))), '   Y : ', num2str(round(bc2(2)))));
          % set(a2, 'FontName', 'Arial', 'FontWeight', 'bold', 'FontSize', 12, 'Color', 'yellow');


    end

     for(object3=1:length(stats3))
bb3=stats3(object3).BoundingBox;
bc3=stats3(object3).Centroid;
rectangle(app.UIAxesVid,'Position',bb3,'EdgeColor','r','LineWidth',2)
plot(app.UIAxesVid,bc3(1),bc3(2),'-m+')
    drawnow;
%a3=text(app.UIAxesVid,bc3(1)+15,bc3(2), strcat('X : ', num2str(round(bc3(1))), '   Y : ', num2str(round(bc3(2)))));
         %  set(a3, 'FontName', 'Arial', 'FontWeight', 'bold', 'FontSize', 12, 'Color', 'yellow');

    
     end


if((isempty(BR)==1)&&(isempty(BG)==1)&&(isempty(BB)==1))
  
app.anguloData.Text="Colóquese correctamente los marcadores";

 else
     

ab1 = polyfit([bc1(1),bc1(2)],[bc3(1),bc3(2)],1);
ab2 = polyfit([bc1(1),bc1(2)],[bc2(1),bc2(2)],1);


vect1 = [1 ab1(1) 1]; % Create a vector based on the line equation
vect2 = [1 ab2(1) 1];

dp = dot(vect1, vect2);
%Compute vector lengths

length1 = sqrt(sum(vect1.^2));
length2 = sqrt(sum(vect2.^2));
%Obtain the larger angle of intersection in degrees

angle = atan2d(norm(cross(vect1,vect2)),dot(vect1,vect2));
% Descomentar solo para graficar codo: Cálculo inverso para que la curva sea hacia arriba
% angle=(2*angle);
% angle=180-angle;
  app.anguloData.Text=num2str(angle);


if((isempty(BR)==1)&&(isempty(BG)==1)&&(isempty(BB)==1))
   
else 
     plot(app.UIAxesVid,[bc1(1),bc3(1)],[bc1(2),bc3(2)],"g",LineWidth=2);
 drawnow;
 plot(app.UIAxesVid,[bc1(1),bc2(1)],[bc1(2),bc2(2)],"g",LineWidth=2);
drawnow;


end


end


 app.curvafiltrada(a)=angle;
 %ROBOT:

% 1 angulo imagen:
% fprintf(ar,'%s',char(angle));


% Si quisiésemos mandar 3 ángulos cogidos de los acelerómetros:

%Cargar código de 3 en arduino
% 
%  fprintf(ar,'%s',char(angle1));
%  fprintf(ar,'%s',char(a));
%  fprintf(ar,'%s',char(,));
%  fprintf(ar,'%s',' ');
% 
%  fprintf(ar,'%s',char(angle2));
%  fprintf(ar,'%s',char(b));
%  fprintf(ar,'%s',char(,));
%  fprintf(ar,'%s',' ');
%  
%  fprintf(ar,'%s',char(angle3));
%  fprintf(ar,'%s',char(c));
%  fprintf(ar,'%s',char(,));
%  fprintf(ar,'%s','\n');

% Cada iteración

end

% ROBOT
% fclose(ar);


    stop(vid);
    flushdata(vid);
    clear all;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
           
             plot(app.UIAxes,app.curvafiltrada,'k');
             %app.ejeAleatorioTiempo,
               analisisBiomec(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RGB_Detect = uitab(app.TabGroup);
            app.RGB_Detect.Title = 'RGB_Detect';
            app.UIAxesVid = uiaxes(app.RGB_Detect);
            title(app.UIAxesVid, 'Análisis a Tiempo real')
            app.UIAxesVid.XTick = [];
            app.UIAxesVid.XTickLabel = '';
            app.UIAxesVid.YTick = [];
            app.UIAxesR = uiaxes(app.RGB_Detect);
            title(app.UIAxesR, 'Red Marker')
            app.UIAxesR.XTick = [];
            app.UIAxesR.YTick = [];
            app.UIAxesB = uiaxes(app.RGB_Detect);
            title(app.UIAxesB, 'Blue Marker')
            app.UIAxesB.XTick = [];
            app.UIAxesB.XTickLabel = '';
            app.UIAxesB.YTick = [];
            app.UIAxesB.YTickLabel = '';
            app.UIAxesG = uiaxes(app.RGB_Detect);
            title(app.UIAxesG, 'Green Marker')
            app.UIAxesG.XTick = [];
            app.UIAxesG.XTickLabel = '';
            app.UIAxesG.YTick = [];
            app.PercentErrorB = uilabel(app.RGB_Detect);
            app.PercentErrorB.Text = '';
            app.ErrorB = uilabel(app.RGB_Detect);
            app.ErrorB.Text = '';
            app.PercentErrorG = uilabel(app.RGB_Detect);
            app.PercentErrorG.Text = '';
            app.ErrorG = uilabel(app.RGB_Detect);
            app.ErrorG.Text = '';
            app.PercentErrorR = uilabel(app.RGB_Detect);
            app.PercentErrorR.Text = '';
            app.ErrorR = uilabel(app.RGB_Detect);
            app.ErrorR.Text = '';
            app.nguloLabel = uilabel(app.RGB_Detect);
            app.nguloLabel.Text = 'Ángulo (º) :';
            app.anguloData = uilabel(app.RGB_Detect);
            app.anguloData.Text = '0';
            app.InicioButton = uibutton(app.RGB_Detect, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.NErroresErrorLabel = uilabel(app.RGB_Detect);
            app.NErroresErrorLabel.Text = 'Nº Errores  % Error';
            app.NErroresErrorLabel_2 = uilabel(app.RGB_Detect);
            app.NErroresErrorLabel_2.Text = 'Nº Errores  % Error';
            app.NErroresErrorLabel_3 = uilabel(app.RGB_Detect);
            app.NErroresErrorLabel_3.Text = 'Nº Errores  % Error';
            app.AnlisisBiomecnicoTab = uitab(app.TabGroup);
            app.AnlisisBiomecnicoTab.Title = 'Análisis Biomecánico';
            app.UIAxes = uiaxes(app.AnlisisBiomecnicoTab);
            title(app.UIAxes, ' Gráfica ')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, {'Ángulo (º)'; ''})
            zlabel(app.UIAxes, 'Z')
            app.CalcularButton = uibutton(app.AnlisisBiomecnicoTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.SealrealizadaporelpacienteLampLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.SealrealizadaporelpacienteLampLabel.Text = 'Señal realizada por el paciente';
            app.SealrealizadaporelpacienteLamp = uilamp(app.AnlisisBiomecnicoTab);
            app.nguloInicialEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.nguloInicialEditFieldLabel.Text = 'Ángulo Inicial(º)';
            app.nguloInicialEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.nguloFinalEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.nguloFinalEditFieldLabel.Text = 'Ángulo Final (º)';
            app.nguloFinalEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.TiempoSubirsEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.TiempoSubirsEditFieldLabel.Text = 'Tiempo Subir (s) ';
            app.TiempoSubirsEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.TiempoBajarsEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.TiempoBajarsEditFieldLabel.Text = 'Tiempo Bajar (s) ';
            app.TiempoBajarsEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.TiempoTotalsEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.TiempoTotalsEditFieldLabel.Text = 'Tiempo Total (s) ';
            app.TiempoTotalsEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.VelocidadAngularBajadaradsEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.VelocidadAngularBajadaradsEditFieldLabel.Text = 'Velocidad Angular Bajada(rad/s) ';
            app.VelocidadAngularBajadaradsEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.VelocidadAngularSubidaradsEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.VelocidadAngularSubidaradsEditFieldLabel.Text = 'Velocidad Angular Subida(rad/s) ';
            app.VelocidadAngularSubidaradsEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.nguloMaximoEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.nguloMaximoEditFieldLabel.Text = 'Ángulo Maximo (º)';
            app.nguloMaximoEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppDesigner_ProcesadoDeImagen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end