classdef biosoft_menu_principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InicioDeSesinTab                matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        RehabilitacionmiembrossuperioresTab  matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        CerrarSesinButton               matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Image4_2                        matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        EjerciciosMiembroSuperiorLabel  matlab.ui.control.Label
        ElevacindeHombroButton          matlab.ui.control.Button
        ElevacinlaterialdeHombroButton  matlab.ui.control.Button
        FlexindeCodoButton              matlab.ui.control.Button
        RehabilitacionmiembrosinferioresTab  matlab.ui.container.Tab
        Image2_2                        matlab.ui.control.Image
        Panel_3                         matlab.ui.container.Panel
        CerrarSesinButton_2             matlab.ui.control.Button
        Image8                          matlab.ui.control.Image
        ElevacinhaciaellateraldeCaderaButton  matlab.ui.control.Button
        Image7                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        ExtensindeCaderaButton          matlab.ui.control.Button
        SquatButton                     matlab.ui.control.Button
        ElevacindeCaderaButton          matlab.ui.control.Button
        EjerciciosMiembroInferiorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        torso
        brazof
        brazo
        mano
        brazo2
        brazof2
        mano2
       pierna
       pierna2
       gemelo
       gemelo2
       pies
       pies2
    end
    
    methods (Access = private)
        
        function graficarAvatar(app)
                    %Avatar
 
v1= [10,30,0]
v2= [70,30,0]
v3=[70,10,0]
v4=[10,10,0]
v5=[10,30,80]
v6=[70,30,80]
v7=[70,10,80]
v8=[10,10,80]
%cargas del vertice
app.torso= [v1;v2;v3;v4;v5;v6;v7;v8]
%Cada cara esta formada por estos vertices
f=[1,2,6,5; 2,3,7,6;;3,4,8,7;1,4,8,5;1,2,3,4;5,6,7,8]
%pintar las caras
C=[0 0 0; 0 0 1; 0 1 0; 1 0 0; 1 1 0; 1 0 1]
%Un brazo
b1=[70,30,30]
b2=[80,30,30]
b3=[80,10,30]
b4=[70,10,30]
b5=[70,30,80]
b6=[80,30,80]
b7=[80,10,80]
b8=[70,10,80]
app.brazo=[b1;b2;b3;b4;b5;b6;b7;b8]
c2= [0 1 1;0.5 0.5 0.6;0 0 1;1 1 0;1 0 1;1 1 1]
%brazo femur
app.brazof= [70,28,-10;80,28,-10;80,12,-10;70,12,-10;70,28,30;80,28,30;80,12,30;70,12,30]
%mano
app.mano= [70,25,-20;80,25,-20;80,15,-20;70,15,-20;70,25,-10;80,25,-10;80,12,-10;70,12,-10]
app.mano2= [70,-25,-20;80,-25,-20;80,-15,-20;70,-15,-20;70,-25,-10;80,-25,-10;80,-12,-10;70,-12,-10]
app.brazo2=[b1;b2;b3;b4;b5;b6;b7;b8]

%CUERPO ENTERO
app.brazo2= [0,10,80;0,10,30;0,30,30;0,30,80;10,10,80;10,10,30;10,30,30;10,30,80]
app.brazof= [70,28,-10;80,28,-10;80,12,-10;70,12,-10;70,28,30;80,28,30;80,12,30;70,12,30]
app.brazof2= [0,28,-10;10,28,-10;10,12,-10;0,12,-10;0,28,30;10,28,30;10,12,30;0,12,30]
app.mano= [70,25,-20;80,25,-20;80,15,-20;70,15,-20;70,25,-10;80,25,-10;80,12,-10;70,12,-10]
app.mano2= [0,25,-20;10,25,-20;10,15,-20;0,15,-20;0,25,-10;10,25,-10;10,12,-10;0,12,-10]
v1= [10,30,0]
v2= [70,30,0]
v3=[70,10,0]
v4=[10,10,0]
v5=[10,30,80]
v6=[70,30,80]
v7=[70,10,80]
v8=[10,10,80]
app.pierna=[15,30,0;35,30,0;35,10,0;15,10,0;15,30,-40;35,30,-40;35,10,-40;15,10,-40]
app.gemelo=[17,30,-40;32,30,-40;32,10,-40;17,10,-40;17,30,-90;32,30,-90;32,10,-90;17,10,-90]
app.pierna2=[45,30,0;65,30,0;65,10,0;45,10,0;45,30,-40;65,30,-40;65,10,-40;45,10,-40]
app.gemelo2=[47,30,-40;62,30,-40;62,10,-40;47,10,-40;47,30,-90;62,30,-90;62,10,-90;47,10,-90]
app.pies=[17,30,-90;32,30,-90;32,-10,-90;17,-10,-90;17,30,-100;32,30,-100;32,-10,-100;17,-10,-100]
app.pies2=[47,30,-90;62,30,-90;62,-10,-90;47,-10,-90;47,30,-100;62,30,-100;62,-10,-100;47,-10,-100]

 patch(app.UIAxes2,'faces',f,'vertices',app.torso,'FaceVertexCData', C, 'FaceColor','flat')
 patch(app.UIAxes2,'faces',f,'vertices',app.brazo,'FaceVertexCData', C, 'FaceColor','flat')
patch(app.UIAxes2,'faces',f,'vertices',app.brazof,'FaceVertexCData', C, 'FaceColor','flat')
patch(app.UIAxes2,'faces',f,'vertices',app.mano,'FaceVertexCData', C, 'FaceColor','flat')
patch(app.UIAxes2,'faces',f,'vertices',app.brazo2,'FaceVertexCData', C, 'FaceColor','flat')
patch(app.UIAxes2,'faces',f,'vertices',app.brazof2,'FaceVertexCData', C, 'FaceColor','flat')
patch(app.UIAxes2,'faces',f,'vertices',app.mano2,'FaceVertexCData', C, 'FaceColor','flat')
patch(app.UIAxes2,'faces',f,'vertices',app.pierna,'FaceVertexCData', C, 'FaceColor','flat')
patch(app.UIAxes2,'faces',f,'vertices',app.pierna2,'FaceVertexCData', C, 'FaceColor','flat')
patch(app.UIAxes2,'faces',f,'vertices',app.gemelo2,'FaceVertexCData', C, 'FaceColor','flat')
patch(app.UIAxes2,'faces',f,'vertices',app.gemelo,'FaceVertexCData', C, 'FaceColor','flat')
patch(app.UIAxes2,'faces',f,'vertices',app.pies,'FaceVertexCData', C, 'FaceColor','flat')
patch(app.UIAxes2,'faces',f,'vertices',app.pies2,'FaceVertexCData', C, 'FaceColor','flat')
view(app.UIAxes2,3)
axis(app.UIAxes2,'image')
hold (app.UIAxes2,'on')
[x,y,z]= sphere;
surf(app.UIAxes2,25*x+40,25*y+20,25*z+105)
surf(x,y,z)
drawnow
hold (app.UIAxes2,'on')

        
    end
            
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function IniciarButtonPushed(app, event)
    graficarAvatar(app)

        end

        % Button pushed function: FlexindeCodoButton
        function FlexindeCodoButtonPushed(app, event)
            biosoft_explicacion_flexion_codo
            delete(app)
        end

        % Button pushed function: CerrarSesinButton
        function CerrarSesinButtonPushed(app, event)
            Salidaapp
            delete(app)
        end

        % Callback function
        function FlexindeHombroButtonPushed(app, event)
            biosoft_explicacion_flexion_hombro
            delete(app)
        end

        % Button pushed function: ElevacindeHombroButton
        function ElevacindeHombroButtonPushed2(app, event)
             biosoft_explicacion_flexion_hombro
                delete(app)
        end

        % Button pushed function: ElevacinlaterialdeHombroButton
        function ElevacinlaterialdeHombroButtonPushed(app, event)
              biosoft_explicacion_abduccion_hombro
                delete(app)
        end

        % Button pushed function: SquatButton
        function SquatButtonPushed(app, event)
            %biosoft_explicacion_sentadilla
            Ventana_Actualizar
            %delete(app)
        end

        % Button pushed function: ElevacindeCaderaButton
        function ElevacindeCaderaButtonPushed(app, event)
            %biosoft_explicacion_flexion_cadera
            Ventana_Actualizar
            %delete(app)
        end

        % Button pushed function: ElevacinhaciaellateraldeCaderaButton
        function ElevacinhaciaellateraldeCaderaButtonPushed(app, event)
            %biosoft_explicacion_abduccion
            Ventana_Actualizar
            %delete(app)
        end

        % Button pushed function: CerrarSesinButton_2
        function CerrarSesinButton_2Pushed(app, event)
             Salidaapp
            delete(app)
        end

        % Button pushed function: ExtensindeCaderaButton
        function ExtensindeCaderaButtonPushed(app, event)
            %biosoft_explicacion_extension_rodilla
            Ventana_Actualizar
           % delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InicioDeSesinTab = uitab(app.TabGroup);
            app.InicioDeSesinTab.Title = 'Inicio De Sesión';
            app.TabGroup4 = uitabgroup(app.InicioDeSesinTab);
            app.RehabilitacionmiembrossuperioresTab = uitab(app.TabGroup4);
            app.RehabilitacionmiembrossuperioresTab.Title = 'Rehabilitacion miembros superiores';
            app.Panel_4 = uipanel(app.RehabilitacionmiembrossuperioresTab);
            app.FlexindeCodoButton = uibutton(app.Panel_4, 'push');
            app.FlexindeCodoButton.ButtonPushedFcn = createCallbackFcn(app, @FlexindeCodoButtonPushed, true);
            app.FlexindeCodoButton.Text = 'Flexión de Codo';
            app.ElevacinlaterialdeHombroButton = uibutton(app.Panel_4, 'push');
            app.ElevacinlaterialdeHombroButton.ButtonPushedFcn = createCallbackFcn(app, @ElevacinlaterialdeHombroButtonPushed, true);
            app.ElevacinlaterialdeHombroButton.Text = {'Elevación laterial '; 'de Hombro'; ''; ''};
            app.ElevacindeHombroButton = uibutton(app.Panel_4, 'push');
            app.ElevacindeHombroButton.ButtonPushedFcn = createCallbackFcn(app, @ElevacindeHombroButtonPushed2, true);
            app.ElevacindeHombroButton.Text = {'Elevación'; ' de Hombro'; ''};
            app.EjerciciosMiembroSuperiorLabel = uilabel(app.Panel_4);
            app.EjerciciosMiembroSuperiorLabel.Text = 'Ejercicios Miembro Superior';
            app.Image3 = uiimage(app.Panel_4);
            app.Image3.ImageSource = 'Captura de pantalla 2022-12-24 123336.png';
            app.Image4 = uiimage(app.Panel_4);
            app.Image4.ImageSource = 'Captura de pantalla 2022-12-24 130949.png';
            app.Image4_2 = uiimage(app.Panel_4);
            app.Image4_2.ImageSource = 'Captura de pantalla 2022-12-24 130144.png';
            app.Image2 = uiimage(app.Panel_4);
            app.Image2.ImageSource = 'Captura de pantalla 2021-03-11 092442.png';
            app.CerrarSesinButton = uibutton(app.Panel_4, 'push');
            app.CerrarSesinButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarSesinButtonPushed, true);
            app.CerrarSesinButton.Text = {'Cerrar Sesión'; ''; ''; ''};
            app.RehabilitacionmiembrosinferioresTab = uitab(app.TabGroup4);
            app.RehabilitacionmiembrosinferioresTab.Title = 'Rehabilitacion miembros inferiores';
            app.Panel_3 = uipanel(app.RehabilitacionmiembrosinferioresTab);
            app.EjerciciosMiembroInferiorLabel = uilabel(app.Panel_3);
            app.EjerciciosMiembroInferiorLabel.Text = {'Ejercicios Miembro Inferior'; ''; ''};
            app.ElevacindeCaderaButton = uibutton(app.Panel_3, 'push');
            app.ElevacindeCaderaButton.ButtonPushedFcn = createCallbackFcn(app, @ElevacindeCaderaButtonPushed, true);
            app.ElevacindeCaderaButton.Text = {'Elevación de Cadera'; ''; ''};
            app.SquatButton = uibutton(app.Panel_3, 'push');
            app.SquatButton.ButtonPushedFcn = createCallbackFcn(app, @SquatButtonPushed, true);
            app.SquatButton.Text = 'Squat';
            app.ExtensindeCaderaButton = uibutton(app.Panel_3, 'push');
            app.ExtensindeCaderaButton.ButtonPushedFcn = createCallbackFcn(app, @ExtensindeCaderaButtonPushed, true);
            app.ExtensindeCaderaButton.Text = {'Extensión de Cadera'; ''; ''};
            app.Image5 = uiimage(app.Panel_3);
            app.Image5.ImageSource = 'descarga.jpg';
            app.Image6 = uiimage(app.Panel_3);
            app.Image6.ImageSource = 'Captura de pantalla 2022-12-24 132341.png';
            app.Image7 = uiimage(app.Panel_3);
            app.Image7.ImageSource = 'Captura de pantalla 2022-12-24 131425.png';
            app.ElevacinhaciaellateraldeCaderaButton = uibutton(app.Panel_3, 'push');
            app.ElevacinhaciaellateraldeCaderaButton.ButtonPushedFcn = createCallbackFcn(app, @ElevacinhaciaellateraldeCaderaButtonPushed, true);
            app.ElevacinhaciaellateraldeCaderaButton.Text = {'Elevación hacia el lateral de Cadera'; ''; ''};
            app.Image8 = uiimage(app.Panel_3);
            app.Image8.ImageSource = 'Captura de pantalla 2022-12-24 133429.png';
            app.CerrarSesinButton_2 = uibutton(app.Panel_3, 'push');
            app.CerrarSesinButton_2.ButtonPushedFcn = createCallbackFcn(app, @CerrarSesinButton_2Pushed, true);
            app.CerrarSesinButton_2.Text = {'Cerrar Sesión'; ''; ''; ''};
            app.Image2_2 = uiimage(app.RehabilitacionmiembrosinferioresTab);
            app.Image2_2.ImageSource = 'Captura de pantalla 2021-03-11 092442.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = biosoft_menu_principal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end