classdef informe_usuario < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InicioDeSesinTab                matlab.ui.container.Tab
        IniciarConexinButton_2          matlab.ui.control.Button
        UnavezlostresindicadoresestenenverdepulseelbotnLabel_3  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        FinalizarButton                 matlab.ui.control.Button
        UnavezlostresindicadoresestenenverdepulseelbotnLabel_2  matlab.ui.control.Label
        IniciarEjercicioButton          matlab.ui.control.Button
        CalibrarButton                  matlab.ui.control.Button
        HombroLampLabel                 matlab.ui.control.Label
        HombroLamp                      matlab.ui.control.Lamp
        CodoLampLabel                   matlab.ui.control.Label
        CodoLamp                        matlab.ui.control.Lamp
        MuecaLamp                       matlab.ui.control.Lamp
        MuecaLampLabel                  matlab.ui.control.Label
        UnavezlostresindicadoresestenenverdepulseelbotnLabel  matlab.ui.control.Label
        MantenlaposicininicialhastaqueloscoloresestenenverdeLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        ColoqueselossensoresenlasposicionesdondeLabel  matlab.ui.control.Label
        INSTRUCCIONESPARAREALIZARUNASESINLabel  matlab.ui.control.Label
        InformeDelUsuarioTab            matlab.ui.container.Tab
        ExportaraexcelButton            matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        PesoLabel                       matlab.ui.control.Label
        AlturaLabel                     matlab.ui.control.Label
        CausaLabel                      matlab.ui.control.Label
        DNILabel_2                      matlab.ui.control.Label
        ApellidosLabel                  matlab.ui.control.Label
        NombreLabel                     matlab.ui.control.Label
        VolverAlMenButton               matlab.ui.control.Button
        PESOLabel                       matlab.ui.control.Label
        ALTURALabel                     matlab.ui.control.Label
        CAUSADELAREHABILITACINLabel     matlab.ui.control.Label
        DNILabel                        matlab.ui.control.Label
        APELLIDOSLabel                  matlab.ui.control.Label
        NOMBRELabel                     matlab.ui.control.Label
        InformeDeUsuarioLabel           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        AnlisisBiomecnicoTab            matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CodoTab                         matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        nguloMaximoEditField            matlab.ui.control.NumericEditField
        nguloMaximoEditFieldLabel       matlab.ui.control.Label
        VelocidadAngularSubidaradsEditField  matlab.ui.control.NumericEditField
        VelocidadAngularSubidaradsEditFieldLabel  matlab.ui.control.Label
        VelocidadAngularBajadaradsEditField  matlab.ui.control.NumericEditField
        VelocidadAngularBajadaradsEditFieldLabel  matlab.ui.control.Label
        TiempoTotalsEditField           matlab.ui.control.NumericEditField
        TiempoTotalsEditFieldLabel      matlab.ui.control.Label
        TiempoBajarsEditField           matlab.ui.control.NumericEditField
        TiempoBajarsEditFieldLabel      matlab.ui.control.Label
        TiempoSubirsEditField           matlab.ui.control.NumericEditField
        TiempoSubirsEditFieldLabel      matlab.ui.control.Label
        nguloFinalEditField             matlab.ui.control.NumericEditField
        nguloFinalEditFieldLabel        matlab.ui.control.Label
        nguloInicialEditField           matlab.ui.control.NumericEditField
        nguloInicialEditFieldLabel      matlab.ui.control.Label
        SealrealizadaporelpacienteLamp  matlab.ui.control.Lamp
        SealrealizadaporelpacienteLampLabel  matlab.ui.control.Label
        SealidealLamp                   matlab.ui.control.Lamp
        SealidealLampLabel              matlab.ui.control.Label
        PorcentajedeerrorEditFieldLabel  matlab.ui.control.Label
        PorcentajedeerrorEditField      matlab.ui.control.NumericEditField
        DiferenciadeTiemposEditFieldLabel  matlab.ui.control.Label
        DiferenciadeTiemposEditField    matlab.ui.control.NumericEditField
        AnlisisBiomecnicoLabel          matlab.ui.control.Label
        AnalisisBiomecnicoButton        matlab.ui.control.Button
        MostrarSealesButton             matlab.ui.control.Button
        CiclosDetalladosButton          matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        HombroTab                       matlab.ui.container.Tab
        nguloMaximoEditField_2          matlab.ui.control.NumericEditField
        nguloMaximoEditField_2Label     matlab.ui.control.Label
        VelocidadAngularSubidaradsEditField_2  matlab.ui.control.NumericEditField
        VelocidadAngularSubidaradsEditField_2Label  matlab.ui.control.Label
        VelocidadAngularBajadaradsEditField_2  matlab.ui.control.NumericEditField
        VelocidadAngularBajadaradsEditField_2Label  matlab.ui.control.Label
        TiempoTotalsEditField_2         matlab.ui.control.NumericEditField
        TiempoTotalsEditField_2Label    matlab.ui.control.Label
        TiempoBajarsEditField_2         matlab.ui.control.NumericEditField
        TiempoBajarsEditField_2Label    matlab.ui.control.Label
        TiempoSubirsEditField_2         matlab.ui.control.NumericEditField
        TiempoSubirsEditField_2Label    matlab.ui.control.Label
        nguloFinalEditField_2           matlab.ui.control.NumericEditField
        nguloFinalEditField_2Label      matlab.ui.control.Label
        nguloInicialEditField_2         matlab.ui.control.NumericEditField
        nguloInicialEditField_2Label    matlab.ui.control.Label
        SealrealizadaporelpacienteLamp_2  matlab.ui.control.Lamp
        SealrealizadaporelpacienteLamp_2Label  matlab.ui.control.Label
        SealidealLamp_2                 matlab.ui.control.Lamp
        SealidealLamp_2Label            matlab.ui.control.Label
        PorcentajedeerrorEditField_2Label  matlab.ui.control.Label
        PorcentajedeerrorEditField_2    matlab.ui.control.NumericEditField
        DiferenciadeTiemposEditField_2Label  matlab.ui.control.Label
        DiferenciadeTiemposEditField_2  matlab.ui.control.NumericEditField
        AnlisisBiomecnicoLabel_2        matlab.ui.control.Label
        AnalisisBiomecnicoButton_2      matlab.ui.control.Button
        MostrarSealesButton_2           matlab.ui.control.Button
        CiclosDetalladosButton_2        matlab.ui.control.Button
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        MuecaTab                        matlab.ui.container.Tab
        nguloMaximoEditField_3          matlab.ui.control.NumericEditField
        nguloMaximoEditField_3Label     matlab.ui.control.Label
        VelocidadAngularSubidaradsEditField_3  matlab.ui.control.NumericEditField
        VelocidadAngularSubidaradsEditField_3Label  matlab.ui.control.Label
        VelocidadAngularBajadaradsEditField_3  matlab.ui.control.NumericEditField
        VelocidadAngularBajadaradsEditField_3Label  matlab.ui.control.Label
        TiempoTotalsEditField_3         matlab.ui.control.NumericEditField
        TiempoTotalsEditField_3Label    matlab.ui.control.Label
        TiempoBajarsEditField_3         matlab.ui.control.NumericEditField
        TiempoBajarsEditField_3Label    matlab.ui.control.Label
        TiempoSubirsEditField_3         matlab.ui.control.NumericEditField
        TiempoSubirsEditField_3Label    matlab.ui.control.Label
        nguloFinalEditField_3           matlab.ui.control.NumericEditField
        nguloFinalEditField_3Label      matlab.ui.control.Label
        nguloInicialEditField_3         matlab.ui.control.NumericEditField
        nguloInicialEditField_3Label    matlab.ui.control.Label
        SealrealizadaporelpacienteLamp_3  matlab.ui.control.Lamp
        SealrealizadaporelpacienteLamp_3Label  matlab.ui.control.Label
        SealidealLamp_3                 matlab.ui.control.Lamp
        SealidealLamp_3Label            matlab.ui.control.Label
        PorcentajedeerrorEditField_3Label  matlab.ui.control.Label
        PorcentajedeerrorEditField_3    matlab.ui.control.NumericEditField
        DiferenciadeTiemposEditField_3Label  matlab.ui.control.Label
        DiferenciadeTiemposEditField_3  matlab.ui.control.NumericEditField
        AnlisisBiomecnicoLabel_3        matlab.ui.control.Label
        AnalisisBiomecnicoButton_3      matlab.ui.control.Button
        MostrarSealesButton_3           matlab.ui.control.Button
        CiclosDetalladosButton_3        matlab.ui.control.Button
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        SimulacionconelavatarTab        matlab.ui.container.Tab
        SimulacinAvatarLabel            matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        ReiniciarButton_2               matlab.ui.control.Button
        SimulacinMovimientoPacienteLabel  matlab.ui.control.Label
        PararButton_2                   matlab.ui.control.Button
        IniciarButton_2                 matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        ReiniciarButton                 matlab.ui.control.Button
        SimulacinMovimientoIdealLabel   matlab.ui.control.Label
        PararButton                     matlab.ui.control.Button
        IniciarButton                   matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property
        curvamu10kinf
        nombre
        peso
        altura
        DNI
        causa 
        apellidos 
        ejexmueca
        tiempo_seg
        curvafiltradamueca
        curvafiltradacodo
        curvafiltradahombro
        ejexmue10k
        ejexmueca10k
        curvam
        curvamueca10k
        curvamu10k
        curvamu10ksup
        ejexhombro
        curvah10k
        curvah10ksup
        curvah10kinf
        curvah
        curvahombro10k
        ejexh10k
        ejexcodo
        ejexc10k
        imu
        valor1
        a=0
        parar
        imu1
        curvac10k
        imu2
        imu3
arduinoObj
        pinta
        valor
         curva
        curva10k
        eje10s
        curvaVariable
        ejeAleatorioTiempo
        ejex
        ejex10k
        curvam10k
        curvanueva10k
        curvafiltrada
     introduccion_datos_del_paciente
       torso
        brazof
        brazo
        mano
        brazo2
        brazof2
        mano2
        pierna
        pierna2
        gemelo
        gemelo2
        pies
        pies2
        gradoDeCalidad
        f
        x
        y
        C
        curva10ksup
        curva10kinf
        tiempod
        AngInic
       AngFin
       AngMax;
       Tiempo1_2
       Tiempo2_3
       TiempoTotal
       VelocAng1_2
       VelocAng2_3
       pasopintado
       pinta2
       pasopintado2
     
    end


    methods (Access = private)
        
        function filtrar(app)
             
             fs=5000;
%Filtramos a 10Hz para no ser agresivos
wcfpb=10/(fs/2);
sincpb=wcfpb*sinc(wcfpb*(-3000:3000));

%Filtramos la señal
curvafR =conv(app.imu1,sincpb);
tfcurvafR= fftshift(fft(curvafR));
app.curvafiltrada = ifft(ifftshift(tfcurvafR),'symmetric');
%Ajustamos el eje a 10000 muestras.
app.curvafiltrada=app.curvafiltrada(3001:end-3000);
app.ejex10k=linspace(0,100,10000);
        app.curvafiltrada= makima(app.ejex,app.curvafiltrada,app.ejex10k);
        %hacerlo mas preciso
        pp = makima(app.ejex,app.curvafiltrada);
        app.curvafiltrada=ppval(pp,linspace(0,100,10000));
              end
        
        
        function generarAvatar(app)
            v1= [10,30,0];
            v2= [70,30,0];
            v3=[70,10,0];
            v4=[10,10,0];
            v5=[10,30,80];
            v6=[70,30,80];
            v7=[70,10,80];
            v8=[10,10,80];
            %cargas del vertice
            app.torso= [v1;v2;v3;v4;v5;v6;v7;v8];
            %Cada cara esta formada por estos vertices
            app.f=[1,2,6,5; 2,3,7,6;3,4,8,7;1,4,8,5;1,2,3,4;5,6,7,8];
            %pintar las caras
            app.C= [0.30,0.75,0.93;0.30,0.75,0.93;0.30,0.75,0.93;0.30,0.75,0.93;1 1 1;1 1 1];
            %Un brazo
            b1=[70,30,30];
            b2=[80,30,30];
            b3=[80,10,30];
            b4=[70,10,30];
            b5=[70,30,80];
            b6=[80,30,80];
            b7=[80,10,80];
            b8=[70,10,80];
            app.brazo=[b1;b2;b3;b4;b5;b6;b7;b8];
            %c2= [0 1 1;0.5 0.5 0.6;0 0 1;1 1 0;1 0 1;1 1 1];

            %brazo femur
            app.brazof= [70,28,-10;80,28,-10;80,12,-10;70,12,-10;70,28,30;80,28,30;80,12,30;70,12,30];

            %mano
            app.mano= [70,25,-20;80,25,-20;80,15,-20;70,15,-20;70,25,-10;80,25,-10;80,12,-10;70,12,-10];

            app.mano2= [70,-25,-20;80,-25,-20;80,-15,-20;70,-15,-20;70,-25,-10;80,-25,-10;80,-12,-10;70,-12,-10];
            app.brazo2=[b1;b2;b3;b4;b5;b6;b7;b8];

            %CUERPO ENTERO
            app.brazo2= [0,10,80;0,10,30;0,30,30;0,30,80;10,10,80;10,10,30;10,30,30;10,30,80];
            app.brazof= [70,28,-10;80,28,-10;80,12,-10;70,12,-10;70,28,30;80,28,30;80,12,30;70,12,30];
            app.brazof2= [0,28,-10;10,28,-10;10,12,-10;0,12,-10;0,28,30;10,28,30;10,12,30;0,12,30];
            app.mano= [70,25,-20;80,25,-20;80,15,-20;70,15,-20;70,25,-10;80,25,-10;80,12,-10;70,12,-10];
            app.mano2= [0,25,-20;10,25,-20;10,15,-20;0,15,-20;0,25,-10;10,25,-10;10,12,-10;0,12,-10];
            v1= [10,30,0];
            v2= [70,30,0];
            v3=[70,10,0];
            v4=[10,10,0];
            v5=[10,30,80];
            v6=[70,30,80];
            v7=[70,10,80];
            v8=[10,10,80];
            app.pierna=[15,30,0;35,30,0;35,10,0;15,10,0;15,30,-40;35,30,-40;35,10,-40;15,10,-40];
            app.gemelo=[17,30,-40;32,30,-40;32,10,-40;17,10,-40;17,30,-90;32,30,-90;32,10,-90;17,10,-90];
            app.pierna2=[45,30,0;65,30,0;65,10,0;45,10,0;45,30,-40;65,30,-40;65,10,-40;45,10,-40];
            app.gemelo2=[47,30,-40;62,30,-40;62,10,-40;47,10,-40;47,30,-90;62,30,-90;62,10,-90;47,10,-90];
            app.pies=[17,30,-90;32,30,-90;32,-10,-90;17,-10,-90;17,30,-100;32,30,-100;32,-10,-100;17,-10,-100];
            app.pies2=[47,30,-90;62,30,-90;62,-10,-90;47,-10,-90;47,30,-100;62,30,-100;62,-10,-100;47,-10,-100];

        end
        function graficarMovIdeal(app)
            generarAvatar(app)
          graficaideal(app)


            function R = rotationMatrix(yaw,pitch,roll)
%UNTITLED2 Summary of this function goes here
%   Detailed explanation goes here
matrixYaw = [cosd(yaw),-sind(yaw),0;...
    sind(yaw),cosd(yaw),0;0,0,1];
matrixPitch = [cosd(pitch),0,sind(pitch);...
    0,1,0;-sind(pitch),0,cosd(pitch)];
matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);...
    0,sind(roll),cosd(roll)];
R = matrixYaw*matrixPitch*matrixRoll;
        end
        function modelRotated = applyRotationNx3(model,...
    displacement,R)
modelRotated = R*(model-repmat(displacement,size(model,1),1))';
modelRotated = modelRotated' + repmat(displacement,size(model,1),1);
end
           while app.pinta ==1
            mov=29;
           
            %plot(app.curvanueva10k);
            for i=app.pasopintado:floor(length(app.curva10k)/mov):length(app.curva10k)
                YPRtorso=[0,0,0];
                YPRhombro= [0,0,0];
                % Cojo la flexion que toca
                YPRcodo=[0,0,0];
                YPRwrist=[0,0,0];

                %Convertimos los vectores en una matriz rotación de cada elemento
                Rtorso = rotationMatrix(0,0,0);
                Rhombro = rotationMatrix(0,0,0);
                Rcodo = rotationMatrix(0,0, -app.curva10k(i));
                Rwrist = rotationMatrix(0,0,0);
                %para definir el eje de rotacion
                manoRotada = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(app.mano,...
                    sum(app.brazof(1:4,:))/4,Rwrist),sum(app.brazo(1:4,:))/4,Rcodo),(app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %En el antebrazo tenemos tres rotaciones, la primera segun la rotacion del
                %codo despues del hombro despues la del torso
                antebrazoRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(app.brazof,...
                    sum(app.brazo(1:4,:))/4,Rcodo),(app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %Se aplican dos rotaciones, la primera se rota el brazo donde su centro de
                %rotacion está cogiendo el punto 5 y 6 de ese vector torso(la mitad),y
                %aplicale rotacion de hombro .
                %Como ese movimiento hace que tambien rote el torso, ponemos la rotacion
                %del torso sum(torso)/8
                brazoRotado = applyRotationNx3(applyRotationNx3(app.brazo,...
                    (app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %La función de rotacion se le aplica al torso con centro de rotacion
                %sum(torso)/8 (8 son las filas) y tenemos la matriz de rotacion
                torsoRotado = applyRotationNx3(app.torso,...
                    sum(app.torso)/8,Rtorso);

                %Pinto sobre la curva (hold on) el marcador en rojo que va tocando
                %subplot(ax(1)), hold on, plot(eje10k(i),curva10k(i),'ro','MarkerSize',4)
                %En el subplot de la derecha voy a pintar el modelo rotado
                %Selecciono el subplot
                %app.UIAxes(ax(2))
                %Y limpio lo que haya
                cla(app.UIAxes_2)
                %Y ahora pinto. Figura rotada
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.torso,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.brazo,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.brazo2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.brazof2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.mano2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.pierna,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.pierna2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.gemelo2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.gemelo,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.pies,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.pies2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',torsoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',brazoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',antebrazoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',manoRotada,'FaceVertexCData',app.C,'FaceColor','flat')

                view(app.UIAxes_2,3)
                axis(app.UIAxes_2,'image')
                hold(app.UIAxes_2,'on')
                [x,y,z]= sphere;
                surf(app.UIAxes_2,25*x+40,25*y+20,25*z+105)
                %surf(x,y,z)
                drawnow
                pause(0.5)
                hold (app.UIAxes_2,'on')
                if app.pinta==0;
                    app.pasopintado=i;
                    break
                     elseif app.pinta==2;
                     app.pasopintado=1;
                     break
                end
                  app.pasopintado=1;
            end
           end
          
        end
        
        function graficarRandom(app)
            generarAvatar(app)
            
           function R = rotationMatrix(yaw,pitch,roll)
%UNTITLED2 Summary of this function goes here
%   Detailed explanation goes here
matrixYaw = [cosd(yaw),-sind(yaw),0;...
    sind(yaw),cosd(yaw),0;0,0,1];
matrixPitch = [cosd(pitch),0,sind(pitch);...
    0,1,0;-sind(pitch),0,cosd(pitch)];
matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);...
    0,sind(roll),cosd(roll)];
R = matrixYaw*matrixPitch*matrixRoll;
        end
        function modelRotated = applyRotationNx3(model,...
    displacement,R)
modelRotated = R*(model-repmat(displacement,size(model,1),1))';
modelRotated = modelRotated' + repmat(displacement,size(model,1),1);
        end
         while app.pinta2 ==1
            %Movimiento a pintar
            mov=29;
            %Creamos un subplot de dos columnas
            % ax(1)=subplot(1,2,1)
            % ax(2)=subplot(1,2,2)
            %En el primero pintsmos la curva
            % subplot(ax(1)), plot(eje10k,10000)
            %Recorremos la curva para ir pintando cada rotacion
            for i=app.pasopintado2:floor(length(app.curvafiltradacodo)/mov):length(app.curvafiltradacodo)
                YPRtorso=[0,0,0];
                YPRhombro= [0,0,0];
                % Cojo la flexion que toca
                YPRcodo=[0,app.curvafiltradacodo,0];
                YPRwrist=[0,0,0];

                %Convertimos los vectores en una matriz rotación de cada elemento
                Rtorso = rotationMatrix(0,0,0);
                Rhombro = rotationMatrix(0,0,0);
                Rcodo = rotationMatrix(0,0, -app.curvafiltradacodo(i));
                Rwrist = rotationMatrix(0,0,0);
                %para definir el eje de rotacion
                manoRotada = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(app.mano,...
                    sum(app.brazof(1:4,:))/4,Rwrist),sum(app.brazo(1:4,:))/4,Rcodo),(app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %En el antebrazo tenemos tres rotaciones, la primera segun la rotacion del
                %codo despues del hombro despues la del torso
                antebrazoRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(app.brazof,...
                    sum(app.brazo(1:4,:))/4,Rcodo),(app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %Se aplican dos rotaciones, la primera se rota el brazo donde su centro de
                %rotacion está cogiendo el punto 5 y 6 de ese vector torso(la mitad),y
                %aplicale rotacion de hombro .
                %Como ese movimiento hace que tambien rote el torso, ponemos la rotacion
                %del torso sum(torso)/8
                brazoRotado = applyRotationNx3(applyRotationNx3(app.brazo,...
                    (app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %La función de rotacion se le aplica al torso con centro de rotacion
                %sum(torso)/8 (8 son las filas) y tenemos la matriz de rotacion
                torsoRotado = applyRotationNx3(app.torso,...
                    sum(app.torso)/8,Rtorso);

                %Pinto sobre la curva (hold on) el marcador en rojo que va tocando
                %subplot(ax(1)), hold on, plot(eje10k(i),curva10k(i),'ro','MarkerSize',4)
                %En el subplot de la derecha voy a pintar el modelo rotado
                %Selecciono el subplot
                %Y limpio lo que haya
                cla(app.UIAxes2)
                %Y ahora pinto. Figura rotada
                patch(app.UIAxes2,'faces',app.f,'vertices',app.torso,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.brazo,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.brazo2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.brazof2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.mano2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.pierna,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.pierna2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.gemelo2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.gemelo,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.pies,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.pies2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',torsoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',brazoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',antebrazoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',manoRotada,'FaceVertexCData',app.C,'FaceColor','flat')

                view(app.UIAxes2,3)
                axis(app.UIAxes2,'image')
                hold(app.UIAxes2,'on')
                [x,y,z]= sphere;
                surf(app.UIAxes2,25*x+40,25*y+20,25*z+105)
                %surf(x,y,z)
                drawnow
                pause(0.5)
                hold (app.UIAxes2,'on')
                 if app.pinta2==0
                    app.pasopintado2=i;
                    break
                 elseif app.pinta2==2
                     app.pasopintado2=1;
                     break
                 end
                  app.pasopintado2=1;
            end
         end
         
        end


        function analisisBiomecCodo(app)
            %Ángulos
            %x=app.NombreDeLaCurva; 
            %y=x(1);
            % DATO ÁNGULO
            app.AngInic=app.curvafiltradacodo(1);
            app.AngFin=app.curvafiltradacodo(end); % DATO ANGULO
            
            %app.AngInic=30;
            %app.AngFin=30;
            
            MuestraAngInic=0;
            [app.AngMax,MuestraAngMax]=max(app.curvafiltradacodo); %DATO ÁNGULO, el otro nos hace falta para calcular pero no hace falta mostrarlo
            MuestraAngFin=length(app.curvafiltradacodo);
            
            %Tiempos
            muestras=10000;
           % t=app.tiempod+10;
            t= app.tiempo_seg;
            fs=muestras/t;
             
            
            app.Tiempo1_2=((MuestraAngMax-MuestraAngInic)/fs); %DATO TIEMPO
            app.Tiempo2_3=((MuestraAngFin-MuestraAngMax)/fs); %DATO TIEMPO
            app.TiempoTotal=app.tiempo_seg; %DATO TIEMPO


%Velocidad angular. En radianes que es lo correcto
%Convertimos de grados a radianes
RadAngMax=((app.AngMax*pi)/180);
RadAngInic=((app.AngInic*pi)/180);
RadAngFin=((app.AngFin*pi)/180);
%Velocidad angular = w
app.VelocAng1_2=((RadAngMax-RadAngInic)/app.Tiempo1_2); %DATO VELOCIDAD ANGULAR
app.VelocAng2_3=((RadAngMax-RadAngFin)/app.Tiempo2_3); %DATO VELOCIDAD ANGULAR
   

         % app.TiempoTotalsEditField.Value = app.tiempod+10;
           app.TiempoTotalsEditField.Value = app.tiempo_seg;
          app.TiempoBajarsEditField.Value=app.Tiempo1_2;
          app.TiempoSubirsEditField.Value=app.Tiempo2_3;
          app.VelocidadAngularBajadaradsEditField.Value=app.VelocAng1_2;
          app.VelocidadAngularSubidaradsEditField.Value=app.VelocAng2_3;
          app.nguloFinalEditField.Value=app.AngFin;
          app.nguloInicialEditField.Value=app.AngInic;
          app.nguloMaximoEditField.Value=app.AngMax;
         app.DiferenciadeTiemposEditField.Value = app.tiempo_seg - 10;


        end

 function analisisBiomecHombro(app)
            %Ángulos
            %x=app.NombreDeLaCurva; 
            %y=x(1);
            % DATO ÁNGULO
            app.AngInic=app.curvafiltradahombro(1);
            app.AngFin=app.curvafiltradahombro(end); % DATO ANGULO
            
            %app.AngInic=30;
            %app.AngFin=30;
            
            MuestraAngInic=0;
            [app.AngMax,MuestraAngMax]=max(app.curvafiltradahombro); %DATO ÁNGULO, el otro nos hace falta para calcular pero no hace falta mostrarlo
            MuestraAngFin=length(app.curvafiltradahombro);
            
            %Tiempos
            muestras=10000;
           % t=app.tiempod+10;
            t= app.tiempo_seg;
            fs=muestras/t;
             
            
            app.Tiempo1_2=((MuestraAngMax-MuestraAngInic)/fs); %DATO TIEMPO
            app.Tiempo2_3=((MuestraAngFin-MuestraAngMax)/fs); %DATO TIEMPO
            app.TiempoTotal= app.tiempo_seg; %DATO TIEMPO

%Velocidad angular. En radianes que es lo correcto
%Convertimos de grados a radianes
RadAngMax=((app.AngMax*pi)/180);
RadAngInic=((app.AngInic*pi)/180);
RadAngFin=((app.AngFin*pi)/180);
%Velocidad angular = w
app.VelocAng1_2=((RadAngMax-RadAngInic)/app.Tiempo1_2); %DATO VELOCIDAD ANGULAR
app.VelocAng2_3=((RadAngMax-RadAngFin)/app.Tiempo2_3); %DATO VELOCIDAD ANGULAR
   

        %  app.TiempoTotalsEditField.Value = app.tiempod+10;
           app.TiempoTotalsEditField_2.Value = app.tiempo_seg;
          app.TiempoBajarsEditField_2.Value=app.Tiempo1_2;
          app.TiempoSubirsEditField_2.Value=app.Tiempo2_3;
          app.VelocidadAngularBajadaradsEditField_2.Value=app.VelocAng1_2;
          app.VelocidadAngularSubidaradsEditField_2.Value=app.VelocAng2_3;
          app.nguloFinalEditField_2.Value=app.AngFin;
          app.nguloInicialEditField_2.Value=app.AngInic;
          app.nguloMaximoEditField_2.Value=app.AngMax;
         app.DiferenciadeTiemposEditField_2.Value = app.tiempo_seg - 10;
         
 end

 function analisisBiomecMueca(app)
            %Ángulos
            %x=app.NombreDeLaCurva; 
            %y=x(1);
            % DATO ÁNGULO
            app.AngInic=app.curvafiltradamueca(1);
            app.AngFin=app.curvafiltradamueca(end); % DATO ANGULO
            
            %app.AngInic=30;
            %app.AngFin=30;
            
            MuestraAngInic=0;
            [app.AngMax,MuestraAngMax]=max(app.curvafiltradamueca); %DATO ÁNGULO, el otro nos hace falta para calcular pero no hace falta mostrarlo
            MuestraAngFin=length(app.curvafiltradamueca);
            
            %Tiempos
            muestras=10000;
            %t=app.tiempod+10;
            t= app.tiempo_seg;
            fs=muestras/t;
             
            
            app.Tiempo1_2=((MuestraAngMax-MuestraAngInic)/fs); %DATO TIEMPO
            app.Tiempo2_3=((MuestraAngFin-MuestraAngMax)/fs); %DATO TIEMPO
            app.TiempoTotal= app.tiempo_seg; %DATO TIEMPO

%Velocidad angular. En radianes que es lo correcto
%Convertimos de grados a radianes
RadAngMax=((app.AngMax*pi)/180);
RadAngInic=((app.AngInic*pi)/180);
RadAngFin=((app.AngFin*pi)/180);
%Velocidad angular = w
app.VelocAng1_2=((RadAngMax-RadAngInic)/app.Tiempo1_2); %DATO VELOCIDAD ANGULAR
app.VelocAng2_3=((RadAngMax-RadAngFin)/app.Tiempo2_3); %DATO VELOCIDAD ANGULAR
   

          app.TiempoTotalsEditField.Value = app.tiempod+10;
           app.TiempoTotalsEditField_3.Value = app.TiempoTotal;
          app.TiempoBajarsEditField_3.Value=app.Tiempo1_2;
          app.TiempoSubirsEditField_3.Value=app.Tiempo2_3;
          app.VelocidadAngularBajadaradsEditField_3.Value=app.VelocAng1_2;
          app.VelocidadAngularSubidaradsEditField_3.Value=app.VelocAng2_3;
          app.nguloFinalEditField_3.Value=app.AngFin;
          app.nguloInicialEditField_3.Value=app.AngInic;
          app.nguloMaximoEditField_3.Value=app.AngMax;
         app.DiferenciadeTiemposEditField_3.Value = app.tiempo_seg -10;
        end


        %CODO
     function graficaideal(app)
        %app.curva = [0 10 20 30 45 60 70 80 90 100 110 120 130 140 150 160 170 180 170 160 150 140 130 120 110 100 90 80 70 60 45 30 20 10 0];
        app.curva = [0 10 20 30 45 60 70 80 90 100 110 120 130 140 150 140 130 120 110 100 90 80 70 60 45 30 20 10 0];
        app.ejex= linspace(0,100,length(app.curva));
        %Suavizamos la grafica interpolando. Creamos nuevo ejex cogiendo el
        %anterior pero le damos más resolucion entre los valores de 0 a 100
        app.ejex10k=linspace(0,100,10000);
        app.curvam10k= makima(app.ejex,app.curva,app.ejex10k);
        %hacerlo mas preciso
        pp = makima(app.ejex,app.curva);
        app.curva10k=ppval(pp,linspace(0,100,10000));
        app.eje10s= linspace(0,10,length(app.curva10k));
        app.curva10ksup=app.curva10k + 5;
        app.curva10kinf=app.curva10k - 5;
        
     end
     function graficacodo(app)
         app.ejex= linspace(0,100,length(app.imu2));
             ppn=makima(app.ejex,app.imu2);
            app.curvanueva10k=ppval(ppn,linspace(0,100,10000));
            app.curvafiltradacodo = app.curvanueva10k;
     end

     function filtrarcodo(app)
             fs=3000;
%Filtramos a 10Hz para no ser agresivos
wcfpb=10/(fs/2);
sincpb=wcfpb*sinc(wcfpb*(-3000:3000));

%Filtramos la señal
curvafR =conv(app.curvanueva10k,sincpb);
tfcurvafR= fftshift(fft(curvafR));
app.curvafiltrada = ifft(ifftshift(tfcurvafR),'symmetric');
%Ajustamos el eje a 10000 muestras.
app.curvafiltradacodo=app.curvafiltrada(3001:end-3000);
     end

%HOMBRO
function graficahombro(app)
          app.ejexhombro= linspace(0,100,length(app.imu1));
        %hacerlo mas preciso
        pp = makima(app.ejexhombro,app.imu1);
        app.curvahombro10k=ppval(pp,linspace(0,100,10000)); 
        app.curvafiltradahombro = app.curvahombro10k;
end
function graficaidealhombro(app)
    app.curvah = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
        
        app.ejex= linspace(0,100,length(app.curvah));
        %Suavizamos la grafica interpolando. Creamos nuevo ejex cogiendo el
        %anterior pero le damos más resolucion entre los valores de 0 a 100
        app.ejex10k=linspace(0,100,10000);
        app.curvam10k= makima(app.ejex,app.curvah,app.ejex10k);
        %hacerlo mas preciso
        pp = makima(app.ejex,app.curvah);
        app.curvah10k=ppval(pp,linspace(0,100,10000));
        app.eje10s= linspace(0,10,length(app.curvah10k));
        app.curvah10ksup=app.curvah10k + 5;  
        app.curvah10kinf=app.curvah10k - 5; 
     end

function filtrarhombro(app)
             fs=3000;
%Filtramos a 10Hz para no ser agresivos
wcfpb=10/(fs/2);
sincpb=wcfpb*sinc(wcfpb*(-3000:3000));

%Filtramos la señal
curvafR =conv(app.curvahombro10k,sincpb);
tfcurvafR= fftshift(fft(curvafR));
app.curvafiltrada = ifft(ifftshift(tfcurvafR),'symmetric');
%Ajustamos el eje a 10000 muestras.
app.curvafiltradahombro=app.curvafiltrada(3001:end-3000);
     end
    

function filtrarmueca(app)
             fs=3000;
%Filtramos a 10Hz para no ser agresivos
wcfpb=10/(fs/2);
sincpb=wcfpb*sinc(wcfpb*(-3000:3000));

%Filtramos la señal
curvafR =conv(app.curvamueca10k,sincpb);
tfcurvafR= fftshift(fft(curvafR));
app.curvafiltrada = ifft(ifftshift(tfcurvafR),'symmetric');
%Ajustamos el eje a 10000 muestras.
app.curvafiltradamueca=app.curvafiltrada(3001:end-3000);
     end
%MUÑECA
function graficamueca(app)
          app.ejexmueca= linspace(0,100,length(app.imu3));
        pp = makima(app.ejexmueca,app.imu3);
        app.curvamueca10k=ppval(pp,linspace(0,100,10000)); 
        app.curvafiltradamueca =  app.curvamueca10k;
end
     function graficaidealmueca(app)
        app.curvam = [0 0 0 0 0 0 0 0 0 0 0];
        app.ejex= linspace(0,100,length(app.curvam));
        %Suavizamos la grafica interpolando. Creamos nuevo ejex cogiendo el
        %anterior pero le damos más resolucion entre los valores de 0 a 100
        app.ejex10k=linspace(0,100,10000);
        app.curvamu10k= makima(app.ejex,app.curvam,app.ejex10k);
        %hacerlo mas preciso
        pp = makima(app.ejex,app.curvam);
        app.curvamu10k=ppval(pp,linspace(0,100,10000));
        app.eje10s= linspace(0,10,length(app.curvamu10k));
        app.curvamu10ksup=app.curvamu10k + 5; 
        app.curvamu10kinf=app.curvamu10k + 5;
     end

     function ReadYPR(app)

% Read the ASCII data from the serialport object.

data = readline(app.arduinoObj);
data=split(data);
data=str2double(data);

% Convert the string data to numeric type and save it in the UserData
% property of the serialport object.

%src.UserData.Data(end+1) = str2double(data);
 app.arduinoObj.UserData.Data(size(app.arduinoObj.UserData.Data,1)+1,:) = data;

% Update the Count value of the serialport object.
app.arduinoObj.UserData.Count = app.arduinoObj.UserData.Count + 1;

% If 1001 data points have been collected from the Arduino, switch off the
% callbacks and plot the data.
cla(app.UIAxes)
  plot(app.UIAxes,app.arduinoObj.UserData.Data(:,1),'b');
  hold(app.UIAxes,'on')
  plot(app.UIAxes,((app.arduinoObj.UserData.Data(:,2) + 85) / 170) * 150,'r');
  hold(app.UIAxes,'on')
  plot(app.UIAxes,app.arduinoObj.UserData.Data(:,3),'g');
%Hombro
app.imu1 =  app.arduinoObj.UserData.Data(:, 1)';


%Codo
app.imu2 = app.arduinoObj.UserData.Data(:,2)';
app.imu2 =((app.imu2 + 85) / 170) * 150;


%Muñeca
app.imu3 = app.arduinoObj.UserData.Data(:,3)';


%Calculo bueno de la imu 1
%app.imu1 = app.imu2 - app.imu1;
% cla(app.UIAxes)
%   plot(app.UIAxes,app.imu1,'b');
%  hold(app.UIAxes,'on')
%   plot(app.UIAxes,app.imu2,'r');
%   hold(app.UIAxes,'on')
%   plot(app.UIAxes,app.imu3,'g');


end
        
        function results = func10(app)
            
        end
    end    


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, peso, altura, causa, DNI, apellidos, nombre)

app.arduinoObj = serialport("COM4",115200);
configureTerminator(app.arduinoObj,"CR/LF");


        %app.tiempod=tiempod;
        app.EditField.Value = '00:00:00';
        app.EditField.FontSize = 30;
        app.pinta=0;
        app.valor=0;
        app.valor1 =0;
        app.pasopintado=1;
        app.pinta2=0;
          app.pasopintado2=1;
      app.nombre=nombre;
      app.peso =peso;
      app.altura = altura;
      app.DNI = DNI;
      app.causa = causa;
      app.apellidos = apellidos;
      

            app.NombreLabel.Text=nombre;
            app.ApellidosLabel.Text=apellidos;

            app.DNILabel_2.Text=DNI;

            app.CausaLabel.Text=causa;

            app.AlturaLabel.Text=num2str(altura);

            app.PesoLabel.Text=num2str(peso);
        end

        % Button pushed function: VolverAlMenButton
        function VolverAlMenButtonPushed(app, event)
            %fclose(serialport("COM4",115200));
            delete(app.arduinoObj)
            biosoft_menu_principal
            delete(app);
        end

        % Callback function
        function FiltrarButtonPushed(app, event)
 app.valor=~app.valor;
 switch app.valor
     case 1
         app.FiltrarButton.Text ='Señal Original';
            filtrar(app);
         plot(app.UIAxes,app.curvafiltrada,'k');
     case 0
          app.FiltrarButton.Text ='Filtrar';
 plot(app.UIAxes,app.ejeAleatorioTiempo,app.imu1,'k');
 end
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
             graficaideal(app)
            app.pinta =1;
            graficarMovIdeal(app);
            
        end

        % Button pushed function: IniciarButton_2
        function IniciarButton_2Pushed(app, event)
            
            app.pinta2 =1;
            graficacodo(app)
            graficarRandom(app);
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            graficaideal(app)
            app.pinta =0;
            graficarMovIdeal(app);
           
        end

        % Button pushed function: MostrarSealesButton
        function MostrarSealesButtonPushed(app, event)
           graficaideal(app)
           graficacodo(app)
          %filtrarcodo(app)
          app.ejeAleatorioTiempo=linspace(0,app.tiempo_seg,length(app.curvafiltradacodo));
          minimo= 10;
          app.tiempod = app.tiempo_seg-minimo;
             plot(app.UIAxes_3,app.ejeAleatorioTiempo,app.curvafiltradacodo,'k');
             hold (app.UIAxes_3,'on');
             plot(app.UIAxes_3,app.ejeAleatorioTiempo,app.curva10k,'r');   
      y = find(app.curvafiltradacodo>app.curva10ksup);
          x = find(app.curvafiltradacodo<app.curva10kinf);
           z=find(y>0);
           k=length(z);
           i=find(x>0);
           m=length(i);
           muestras=m+k;
           app.gradoDeCalidad=((muestras*100)/length(app.curvafiltradacodo));    
    % app.PorcentajedeerrorEditField.Value = double(app.gradoDeCalidad);

        end

        % Button pushed function: CiclosDetalladosButton
        function CiclosDetalladosButtonPushed(app, event)
            graficaideal(app)
            graficacodo(app)
           % filtrarcodo(app)
            app.ejex= linspace(0,100,length(app.curva10k));
            plot(app.UIAxes_4,app.ejex,app.curva10ksup,'b--');
            hold(app.UIAxes_4,'on');
            plot(app.UIAxes_4,app.ejex,app.curva10kinf,'b--');
            hold(app.UIAxes_4,'on');
            paso = 100;
                for i=1:paso:length(app.ejex)-paso-1 axis(app.UIAxes_4)
                plot(app.UIAxes_4,app.ejex(1:i+paso) ,app.curvafiltradacodo(1:i+paso),"g");
                pause(0.001)
                end
            hold(app.UIAxes_4,'off');
            drawnow;
        end

        % Button pushed function: PararButton_2
        function PararButton_2Pushed(app, event)
             app.pinta2 = 0;
              graficarRandom(app);
        end

        % Button pushed function: AnalisisBiomecnicoButton
        function AnalisisBiomecnicoButtonPushed(app, event)
            %filtrarcodo(app)
             graficaideal(app)
             graficacodo(app)

           y = find(app.curvafiltradacodo>app.curva10ksup);
           x = find(app.curvafiltradacodo<app.curva10kinf);
           z=find(y>0);
           k=length(z);
           i=find(x>0);
           m=length(i);
           muestras=m+k;
           app.gradoDeCalidad=((muestras*100)/length(app.curvafiltrada));
            analisisBiomecCodo(app)
            app.PorcentajedeerrorEditField.Value = double(app.gradoDeCalidad);
            if app.gradoDeCalidad <50
                   app.FeedbackLamp.Color = [ 1.00,0.00,0.00];
                app.FeedbackLampLabel = " El ejercicio se encuentra muy por debajo del movimiento ideal";
               
             
            else if   50<app.gradoDeCalidad &&  app.gradoDeCalidad <80
                       app.FeedbackLamp.Color = [0.93,0.69,0.13];
                app.FeedbackLampLabel = " El ejercicio no está tan mal realizado pero se puede mejorar";
            else
                app.FeedbackLamp.Color = [0.00,1.00,0.00];
                app.FeedbackLampLabel = " El ejercicio se ha realizado correctamente";
            end
            end
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            graficaideal(app)
           app.pinta=2;
           app.pasopintado=1;
           cla(app.UIAxes_2)
           graficarMovIdeal(app);
           app.pinta=1;
           graficarMovIdeal(app);
        end

        % Button pushed function: ReiniciarButton_2
        function ReiniciarButton_2Pushed(app, event)
             app.pinta2=2;
           app.pasopintado2=1;
           cla(app.UIAxes2)
           graficarRandom(app);
           app.pinta2=1;
           graficarRandom(app);
        end

        % Button pushed function: IniciarEjercicioButton
        function IniciarEjercicioButtonPushed(app, event)
           %DATOS
%global parar imu1 imu2 imu3 const
      app.parar = 0;
      muestras = 3;
      %Ciclos
      contador = 1;
     
   % Reemplaza 'COM1' por el nombre del puerto y 9600 por la velocidad adecuada

% Lectura continua de datos hasta que decidas detenerla
% while true
%     data1 = readline(app.arduinoObj);
%     % Procesa los datos leídos como desees
%      flush(app.arduinoObj);
%       app.arduinoObj.UserData = struct("Data",[],"Count",1);
%       app.arduinoObj.UserData.Data=[];
% k=1;
%             while(k<100)
%                 ReadYPR(app);
%                 k=k+1;
%             end
%     % Condición de salida del bucle
%     if app.parar ==1
%         break;
%     end
% end

S_ini=0;
 if strcmpi(app.IniciarEjercicioButton.Text,'Reiniciar')
      app.EditField.Value='00:00:00';
      cla(app.UIAxes)
      app.imu1 = [];
app.imu2 = [];
app.imu3 = [];
      app.IniciarEjercicioButton.Text='Iniciar Ejercicio';
     % app.FinalizarButton.Text='Finalizar';
    return
 
 else                     
c=clock;
m=0;
S_tot=0;
app.a=0;
while app.a==0
    c1=clock;
    if c1(5)~=c(5)
        S_tot=c1(6)-c(6)+60;
        m=m+1;
        c(5)=c1(5);
    else
     S_tot=c1(6)-c(6)+60*m;
    end
    S_tot=S_tot+S_ini;
    A(1)=floor(S_tot/3600);
    A(2)=floor((S_tot-A(1)*3600)/60);
    A(3)=S_tot-A(1)*3600-A(2)*60;
    app.EditField.Value=sprintf('%02.0f:%02.0f:%02.2f',A(1),A(2),A(3));
    % Convertir a entero en segundos
        tiempo_ent = datevec(app.EditField.Value, 'HH:MM:SS');
        app.tiempo_seg = tiempo_ent(4) * 3600 + tiempo_ent(5) * 60 + tiempo_ent(6);
%     app.EditField.Value=[num2str(A(1)) ':' num2str(A(2)) ':' num2str(A(3))];
%     app.EditField.Value=num2str(S_tot);
    pause(0.01);
end
 end      


        end

        % Button pushed function: FinalizarButton
        function FinalizarButtonPushed(app, event)
            %SENSORES
            app.parar = 1;
            app.a == 0;
                    
          
%CRONOMETRO
   
  %end        
%   else 
  %if app.a==0
app.a=1;
% Cierra el puerto en serie
delete(app.arduinoObj);
% app.HombroLamp.Color = [0.00,1.00,1.00];
%              app.CodoLamp.Color = [0.00,1.00,1.00];
%              app.MuecaLamp.Color = [0.00,1.00,1.00];
app.arduinoObj = serialport("COM4",115200);
configureTerminator(app.arduinoObj,"CR/LF");
app.IniciarEjercicioButton.Text='Reiniciar';
%app.FinalizarButton.Text='Finalizar';


        end

        % Button pushed function: ExportaraexcelButton
        function ExportaraexcelButtonPushed(app, event)
              %EXCEL?
              % Agregar una columna adicional con un nombre específico
 

              nombresVectores = {'Nombre', 'Apellidos','DNI','Causa de Rehabilitación','Altura(m)','Peso(kg)','Ángulo Hombro', 'Ángulo Codo', 'Ángulo Muñeca'};
matrizDatos = [app.imu1', app.imu2', app.imu3'];

% Define el nombre de la persona
nombrePersona = app.nombre;

% Agregar una columna adicional con el nombre de la persona
columnaPersona = [nombrePersona; repmat("", size(matrizDatos, 1)-1, 1)];
disp(nombrePersona)
%APELLIDOS
% Define el nombre de la persona
apellidoPersona = app.apellidos;

% Agregar una columna adicional con el nombre de la persona
columnaApellido = [apellidoPersona; repmat("", size(matrizDatos, 1)-1, 1)];
disp(apellidoPersona)

%DNI
% Define el nombre de la persona
dniPersona = app.DNI;

% Agregar una columna adicional con el nombre de la persona
columnadni = [dniPersona; repmat("", size(matrizDatos, 1)-1, 1)];

%CAUSA
causaPersona = app.causa;
% Agregar una columna adicional con el nombre de la persona
columnacausa = [causaPersona; repmat("", size(matrizDatos, 1)-1, 1)];

%ALTURA
alturaPersona = app.altura;
% Agregar una columna adicional con el nombre de la persona
columnaaltura = [alturaPersona; repmat("", size(matrizDatos, 1)-1, 1)];
%PESO
pesoPersona = app.peso;
% Agregar una columna adicional con el nombre de la persona
columnapeso = [pesoPersona; repmat("", size(matrizDatos, 1)-1, 1)];

matrizDatosConNombre = [columnaPersona, columnaApellido,columnadni,columnacausa,columnaaltura,columnapeso,num2cell(matrizDatos)];

% Combinar nombres de vectores y matriz de datos con nombre
matrizDatosConNombres = [nombresVectores; matrizDatosConNombre];

% Exportar los datos a un archivo de Excel utilizando writecell
writematrix(matrizDatosConNombres, 'datos_sesion_flexion_codo.xlsx');

% Mensaje de confirmación
disp('Los datos se han exportado exitosamente.')

        end

        % Button pushed function: CalibrarButton
        function CalibrarButtonPushed(app, event)
       app.parar = 0;
     
      %Ciclos
      contador = 1;
        
       flush(app.arduinoObj);
      app.arduinoObj.UserData = struct("Data",[],"Count",1);
      app.arduinoObj.UserData.Data=[];
      muestras = 3;
             for i=1:15
                 readline(app.arduinoObj)
             end
            % disp (size(app.arduinoObj.UserData.Data));
              app.HombroLamp.Color = [0.00,1.00,0.00];
             app.CodoLamp.Color = [0.00,1.00,0.00];
             app.MuecaLamp.Color = [0.00,1.00,0.00];
             %end

% k=1;
%             while(k<100)
%                 ReadYPR(app);
%                 k=k+1;
%             end

            
% while app.a == 1
%      k=1;
%    
%    
%           if app.parar== 1;
%                     k=k;
%                     break
%           elseif app.a==1;
%               cla(app.UIAxes)
%                      break
%                      k=1;
%           end
%      k=1;
%     end
% end


        end

        % Button pushed function: MostrarSealesButton_2
        function MostrarSealesButton_2Pushed(app, event)
              graficaidealhombro(app)
           graficahombro(app)
           %filtrarhombro(app)
            app.ejeAleatorioTiempo=linspace(0,app.tiempo_seg,length(app.curvafiltradahombro));
          minimo= 10;
          app.tiempod = app.tiempo_seg-minimo;
             plot(app.UIAxes_5,app.ejeAleatorioTiempo,app.curvafiltradahombro,'k');
             hold (app.UIAxes_5,'on');
             plot(app.UIAxes_5,app.ejeAleatorioTiempo,app.curvah10k,'r');   
     y = find(app.curvafiltradahombro>app.curvah10ksup);
          x = find(app.curvafiltradahombro<app.curvah10kinf);
           z=find(y>0);
           k=length(z);
           i=find(x>0);
           m=length(i);
           muestras=m+k;
           app.gradoDeCalidad=((muestras*100)/length(app.curvafiltradahombro));    
    % app.PorcentajedeerrorEditField.Value = double(app.gradoDeCalidad);
        end

        % Button pushed function: MostrarSealesButton_3
        function MostrarSealesButton_3Pushed(app, event)
             graficaidealmueca(app)
           graficamueca(app)
          % filtrarmueca(app)
          app.ejeAleatorioTiempo=linspace(0,app.tiempo_seg,length(app.curvafiltradamueca));
          minimo= 10;
          app.tiempod = app.tiempo_seg-minimo;
             plot(app.UIAxes_7,app.ejeAleatorioTiempo,app.curvafiltradamueca,'k');
             hold (app.UIAxes_7,'on');
             plot(app.UIAxes_7,app.ejeAleatorioTiempo,app.curvamu10k,'r');   
     y = find(app.curvafiltradamueca>app.curvamu10ksup);
          x = find(app.curvafiltradamueca<app.curvamu10kinf);
           z=find(y>0);
           k=length(z);
           i=find(x>0);
           m=length(i);
           muestras=m+k;
           app.gradoDeCalidad=((muestras*100)/length(app.curvafiltradamueca));    
    % app.PorcentajedeerrorEditField.Value = double(app.gradoDeCalidad);
        end

        % Button pushed function: CiclosDetalladosButton_2
        function CiclosDetalladosButton_2Pushed(app, event)
             graficaidealhombro(app)
            graficahombro(app)
            %filtrarhombro(app)
            app.ejex= linspace(0,100,length(app.curvah10k));
            plot(app.UIAxes_6,app.ejex,app.curvah10ksup,'b--');
            hold(app.UIAxes_6,'on');
            plot(app.UIAxes_6,app.ejex,app.curvah10kinf,'b--');
            hold(app.UIAxes_6,'on');
            paso = 100;
                for i=1:paso:length(app.ejex)-paso-1 axis(app.UIAxes_6)
                plot(app.UIAxes_6,app.ejex(1:i+paso),app.curvafiltradahombro(1:i+paso),"g");
                pause(0.001)
                end
            hold(app.UIAxes_6,'off');
            drawnow;
        end

        % Button pushed function: AnalisisBiomecnicoButton_2
        function AnalisisBiomecnicoButton_2Pushed(app, event)
             %filtrarhombro(app)
             graficaidealhombro(app)
             graficahombro(app)
           y = find(app.curvafiltradahombro>app.curvah10ksup);
           x = find(app.curvafiltradahombro<app.curvah10kinf);
           z=find(y>0);
           k=length(z);
           i=find(x>0);
           m=length(i);
           muestras=m+k;
           app.gradoDeCalidad=((muestras*100)/length(app.curvafiltradahombro));
            analisisBiomecHombro(app)
            app.PorcentajedeerrorEditField_2.Value = double(app.gradoDeCalidad);
        end

        % Button pushed function: CiclosDetalladosButton_3
        function CiclosDetalladosButton_3Pushed(app, event)
             graficaidealmueca(app)
            graficamueca(app)
            %filtrarmueca(app)
            app.ejex= linspace(0,100,length(app.curvamu10k));
            plot(app.UIAxes_8,app.ejex,app.curvamu10ksup,'b--');
            hold(app.UIAxes_8,'on');
            plot(app.UIAxes_8,app.ejex,app.curvamu10kinf,'b--'); 
            hold(app.UIAxes_8,'on');
            paso = 100;
                for i=1:paso:length(app.ejex)-paso-1 axis(app.UIAxes_8)
                plot(app.UIAxes_8,app.ejex(1:i+paso),app.curvafiltradamueca(1:i+paso),"g");
                pause(0.001)
                end
            hold(app.UIAxes_8,'off');
            drawnow;
        end

        % Button pushed function: AnalisisBiomecnicoButton_3
        function AnalisisBiomecnicoButton_3Pushed(app, event)
             %filtrarmueca(app)
             graficaidealmueca(app)
             graficamueca(app)
           y = find(app.curvafiltradamueca>app.curvamu10ksup);
           x = find(app.curvafiltradamueca<0);
           z=find(y>0);
           k=length(z);
           i=find(x>0);
           m=length(i);
           muestras=m+k;
           app.gradoDeCalidad=((muestras*100)/length(app.curvafiltradamueca));
            analisisBiomecMueca(app)
            app.PorcentajedeerrorEditField_3.Value = double(app.gradoDeCalidad);
        end

        % Button pushed function: IniciarConexinButton_2
        function IniciarConexinButton_2Pushed(app, event)
           app.parar = 0;
      muestras = 3;
      %Ciclos
      contador = 1; 
            while true
    data1 = readline(app.arduinoObj);
    % Procesa los datos leídos como desees
     flush(app.arduinoObj);
      app.arduinoObj.UserData = struct("Data",[],"Count",1);
      app.arduinoObj.UserData.Data=[];
k=1;
            while(k<100)
                ReadYPR(app);
                k=k+1;
            end
    % Condición de salida del bucle
    if app.parar ==1
        break;
    end
end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Ventana_Robot
             ar = serial('COM6','BaudRate',9600);
             fopen(ar);

% Si queremos mandar 1 angulo imagen:
           fprintf(ar,'%s',char(app.curvafiltradacodo));
           fclose(ar);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InicioDeSesinTab = uitab(app.TabGroup);
            app.InicioDeSesinTab.Title = 'Inicio De Sesión';
            app.INSTRUCCIONESPARAREALIZARUNASESINLabel = uilabel(app.InicioDeSesinTab);
            app.INSTRUCCIONESPARAREALIZARUNASESINLabel.Text = 'INSTRUCCIONES PARA REALIZAR UNA SESIÓN';
            app.ColoqueselossensoresenlasposicionesdondeLabel = uilabel(app.InicioDeSesinTab);
            app.ColoqueselossensoresenlasposicionesdondeLabel.Text = '1º) Coloquese los sensores en las posiciones en los lugares donde se encuentran en la imagen ';
            app.Image2 = uiimage(app.InicioDeSesinTab);
            app.Image2.ImageSource = 'Sensores_biceps.jpg';
            app.MantenlaposicininicialhastaqueloscoloresestenenverdeLabel = uilabel(app.InicioDeSesinTab);
            app.MantenlaposicininicialhastaqueloscoloresestenenverdeLabel.Text = '2º) Manten la posición inicial hasta que los colores y dale al botón de "Calibrar".';
            app.UnavezlostresindicadoresestenenverdepulseelbotnLabel = uilabel(app.InicioDeSesinTab);
            app.UnavezlostresindicadoresestenenverdepulseelbotnLabel.Text = '3º)  Una vez los tres indicadores esten en verde, pulse el botón "Iniciar Conexión".  ';
            app.MuecaLampLabel = uilabel(app.InicioDeSesinTab);
            app.MuecaLampLabel.Text = {'Muñeca'; ''};
            app.MuecaLamp = uilamp(app.InicioDeSesinTab);
            app.CodoLamp = uilamp(app.InicioDeSesinTab);
            app.CodoLampLabel = uilabel(app.InicioDeSesinTab);
            app.CodoLampLabel.Text = 'Codo';
            app.HombroLamp = uilamp(app.InicioDeSesinTab);
            app.HombroLampLabel = uilabel(app.InicioDeSesinTab);
            app.HombroLampLabel.Text = 'Hombro';
            app.CalibrarButton = uibutton(app.InicioDeSesinTab, 'push');
            app.CalibrarButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarButtonPushed, true);
            app.CalibrarButton.Text = 'Calibrar';
            app.IniciarEjercicioButton = uibutton(app.InicioDeSesinTab, 'push');
            app.IniciarEjercicioButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarEjercicioButtonPushed, true);
            app.IniciarEjercicioButton.Text = 'Iniciar Ejercicio';
            app.UnavezlostresindicadoresestenenverdepulseelbotnLabel_2 = uilabel(app.InicioDeSesinTab);
            app.UnavezlostresindicadoresestenenverdepulseelbotnLabel_2.Text = '5º)  Tras finalizar el ejercicio pulse el botón "Finalizar" ';
            app.FinalizarButton = uibutton(app.InicioDeSesinTab, 'push');
            app.FinalizarButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizarButtonPushed, true);
            app.FinalizarButton.Text = 'Finalizar';
            app.EditField = uieditfield(app.InicioDeSesinTab, 'text');
            app.UnavezlostresindicadoresestenenverdepulseelbotnLabel_3 = uilabel(app.InicioDeSesinTab);
            app.UnavezlostresindicadoresestenenverdepulseelbotnLabel_3.Text = '4º)  Pulse a "Iniciar Ejercicio" y realize el ejercicio.';
            app.IniciarConexinButton_2 = uibutton(app.InicioDeSesinTab, 'push');
            app.IniciarConexinButton_2.ButtonPushedFcn = createCallbackFcn(app, @IniciarConexinButton_2Pushed, true);
            app.IniciarConexinButton_2.Text = 'Iniciar Conexión';
            app.InformeDelUsuarioTab = uitab(app.TabGroup);
            app.InformeDelUsuarioTab.Title = 'Informe Del Usuario';
            app.UIAxes = uiaxes(app.InformeDelUsuarioTab);
            title(app.UIAxes, 'Ciclos De Movimiento')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Ángulo (º)')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.InformeDelUsuarioTab);
            app.InformeDeUsuarioLabel = uilabel(app.Panel);
            app.InformeDeUsuarioLabel.Text = {'Informe De Usuario'; ''; ''};
            app.NOMBRELabel = uilabel(app.Panel);
            app.NOMBRELabel.Text = 'NOMBRE:';
            app.APELLIDOSLabel = uilabel(app.Panel);
            app.APELLIDOSLabel.Text = 'APELLIDOS:';
            app.DNILabel = uilabel(app.Panel);
            app.DNILabel.Text = 'D.N.I:';
            app.CAUSADELAREHABILITACINLabel = uilabel(app.Panel);
            app.CAUSADELAREHABILITACINLabel.Text = 'CAUSA DE LA REHABILITACIÓN:';
            app.ALTURALabel = uilabel(app.Panel);
            app.ALTURALabel.Text = 'ALTURA:';
            app.PESOLabel = uilabel(app.Panel);
            app.PESOLabel.Text = 'PESO:';
            app.VolverAlMenButton = uibutton(app.Panel, 'push');
            app.VolverAlMenButton.ButtonPushedFcn = createCallbackFcn(app, @VolverAlMenButtonPushed, true);
            app.VolverAlMenButton.Text = 'Volver Al Menú';
            app.NombreLabel = uilabel(app.Panel);
            app.NombreLabel.Text = {'Nombre'; ''; ''};
            app.ApellidosLabel = uilabel(app.Panel);
            app.ApellidosLabel.Text = 'Apellidos';
            app.DNILabel_2 = uilabel(app.Panel);
            app.DNILabel_2.Text = 'DNI';
            app.CausaLabel = uilabel(app.Panel);
            app.CausaLabel.Text = {'Causa'; ''};
            app.AlturaLabel = uilabel(app.Panel);
            app.AlturaLabel.Text = 'Altura';
            app.PesoLabel = uilabel(app.Panel);
            app.PesoLabel.Text = {'Peso'; ''};
            app.Image = uiimage(app.InformeDelUsuarioTab);
            app.Image.ImageSource = 'Captura de pantalla 2021-03-11 092442.png';
            app.ExportaraexcelButton = uibutton(app.InformeDelUsuarioTab, 'push');
            app.ExportaraexcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportaraexcelButtonPushed, true);
            app.ExportaraexcelButton.Text = 'Exportar a excel';
            app.AnlisisBiomecnicoTab = uitab(app.TabGroup);
            app.AnlisisBiomecnicoTab.Title = 'Análisis Biomecánico';
            app.TabGroup2 = uitabgroup(app.AnlisisBiomecnicoTab);
            app.CodoTab = uitab(app.TabGroup2);
            app.CodoTab.Title = 'Codo';
            app.UIAxes_3 = uiaxes(app.CodoTab);
            title(app.UIAxes_3, 'Comparación Gráfica Ideal')
            xlabel(app.UIAxes_3, 'Tiempo (s)')
            ylabel(app.UIAxes_3, {'Ángulo (º)'; ''})
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.CodoTab);
            title(app.UIAxes_4, ' Señal Filtrada y Normalizada en Rangos')
            xlabel(app.UIAxes_4, 'Ciclo (%)')
            ylabel(app.UIAxes_4, 'Ángulo(º)')
            zlabel(app.UIAxes_4, 'Z')
            app.CiclosDetalladosButton = uibutton(app.CodoTab, 'push');
            app.CiclosDetalladosButton.ButtonPushedFcn = createCallbackFcn(app, @CiclosDetalladosButtonPushed, true);
            app.CiclosDetalladosButton.Text = 'Ciclos Detallados';
            app.MostrarSealesButton = uibutton(app.CodoTab, 'push');
            app.MostrarSealesButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarSealesButtonPushed, true);
            app.MostrarSealesButton.Text = 'Mostrar Señales';
            app.AnalisisBiomecnicoButton = uibutton(app.CodoTab, 'push');
            app.AnalisisBiomecnicoButton.ButtonPushedFcn = createCallbackFcn(app, @AnalisisBiomecnicoButtonPushed, true);
            app.AnalisisBiomecnicoButton.Text = {'Analisis Biomecánico'; ''};
            app.AnlisisBiomecnicoLabel = uilabel(app.CodoTab);
            app.AnlisisBiomecnicoLabel.Text = 'Análisis Biomecánico';
            app.DiferenciadeTiemposEditField = uieditfield(app.CodoTab, 'numeric');
            app.DiferenciadeTiemposEditField.Editable = 'off';
            app.DiferenciadeTiemposEditFieldLabel = uilabel(app.CodoTab);
            app.DiferenciadeTiemposEditFieldLabel.Text = {'Diferencia de Tiempo (s)'; ''};
            app.PorcentajedeerrorEditField = uieditfield(app.CodoTab, 'numeric');
            app.PorcentajedeerrorEditField.Editable = 'off';
            app.PorcentajedeerrorEditFieldLabel = uilabel(app.CodoTab);
            app.PorcentajedeerrorEditFieldLabel.Text = {'Porcentaje de error (%)'; ''};
            app.SealidealLampLabel = uilabel(app.CodoTab);
            app.SealidealLampLabel.Text = 'Señal ideal';
            app.SealidealLamp = uilamp(app.CodoTab);
            app.SealrealizadaporelpacienteLampLabel = uilabel(app.CodoTab);
            app.SealrealizadaporelpacienteLampLabel.Text = 'Señal realizada por el paciente';
            app.SealrealizadaporelpacienteLamp = uilamp(app.CodoTab);
            app.nguloInicialEditFieldLabel = uilabel(app.CodoTab);
            app.nguloInicialEditFieldLabel.Text = 'Ángulo Inicial(º)';
            app.nguloInicialEditField = uieditfield(app.CodoTab, 'numeric');
            app.nguloFinalEditFieldLabel = uilabel(app.CodoTab);
            app.nguloFinalEditFieldLabel.Text = 'Ángulo Final (º)';
            app.nguloFinalEditField = uieditfield(app.CodoTab, 'numeric');
            app.TiempoSubirsEditFieldLabel = uilabel(app.CodoTab);
            app.TiempoSubirsEditFieldLabel.Text = 'Tiempo Subir (s) ';
            app.TiempoSubirsEditField = uieditfield(app.CodoTab, 'numeric');
            app.TiempoBajarsEditFieldLabel = uilabel(app.CodoTab);
            app.TiempoBajarsEditFieldLabel.Text = 'Tiempo Bajar (s) ';
            app.TiempoBajarsEditField = uieditfield(app.CodoTab, 'numeric');
            app.TiempoTotalsEditFieldLabel = uilabel(app.CodoTab);
            app.TiempoTotalsEditFieldLabel.Text = 'Tiempo Total (s) ';
            app.TiempoTotalsEditField = uieditfield(app.CodoTab, 'numeric');
            app.VelocidadAngularBajadaradsEditFieldLabel = uilabel(app.CodoTab);
            app.VelocidadAngularBajadaradsEditFieldLabel.Text = 'Velocidad Angular Bajada(rad/s) ';
            app.VelocidadAngularBajadaradsEditField = uieditfield(app.CodoTab, 'numeric');
            app.VelocidadAngularSubidaradsEditFieldLabel = uilabel(app.CodoTab);
            app.VelocidadAngularSubidaradsEditFieldLabel.Text = 'Velocidad Angular Subida(rad/s) ';
            app.VelocidadAngularSubidaradsEditField = uieditfield(app.CodoTab, 'numeric');
            app.nguloMaximoEditFieldLabel = uilabel(app.CodoTab);
            app.nguloMaximoEditFieldLabel.Text = 'Ángulo Maximo (º)';
            app.nguloMaximoEditField = uieditfield(app.CodoTab, 'numeric');
            app.Button = uibutton(app.CodoTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Captura de pantalla 2023-06-08 125054.jpg';
            app.Button.Text = {''; ''};
            app.HombroTab = uitab(app.TabGroup2);
            app.HombroTab.Title = 'Hombro';
            app.UIAxes_5 = uiaxes(app.HombroTab);
            title(app.UIAxes_5, 'Comparación Gráfica Ideal')
            xlabel(app.UIAxes_5, 'Tiempo (s)')
            ylabel(app.UIAxes_5, {'Ángulo (º)'; ''})
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.HombroTab);
            title(app.UIAxes_6, ' Señal Filtrada y Normalizada en Rangos')
            xlabel(app.UIAxes_6, 'Ciclo (%)')
            ylabel(app.UIAxes_6, 'Ángulo(º)')
            zlabel(app.UIAxes_6, 'Z')
            app.CiclosDetalladosButton_2 = uibutton(app.HombroTab, 'push');
            app.CiclosDetalladosButton_2.ButtonPushedFcn = createCallbackFcn(app, @CiclosDetalladosButton_2Pushed, true);
            app.CiclosDetalladosButton_2.Text = 'Ciclos Detallados';
            app.MostrarSealesButton_2 = uibutton(app.HombroTab, 'push');
            app.MostrarSealesButton_2.ButtonPushedFcn = createCallbackFcn(app, @MostrarSealesButton_2Pushed, true);
            app.MostrarSealesButton_2.Text = 'Mostrar Señales';
            app.AnalisisBiomecnicoButton_2 = uibutton(app.HombroTab, 'push');
            app.AnalisisBiomecnicoButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalisisBiomecnicoButton_2Pushed, true);
            app.AnalisisBiomecnicoButton_2.Text = {'Analisis Biomecánico'; ''};
            app.AnlisisBiomecnicoLabel_2 = uilabel(app.HombroTab);
            app.AnlisisBiomecnicoLabel_2.Text = 'Análisis Biomecánico';
            app.DiferenciadeTiemposEditField_2 = uieditfield(app.HombroTab, 'numeric');
            app.DiferenciadeTiemposEditField_2.Editable = 'off';
            app.DiferenciadeTiemposEditField_2Label = uilabel(app.HombroTab);
            app.DiferenciadeTiemposEditField_2Label.Text = {'Diferencia de Tiempo (s)'; ''};
            app.PorcentajedeerrorEditField_2 = uieditfield(app.HombroTab, 'numeric');
            app.PorcentajedeerrorEditField_2.Editable = 'off';
            app.PorcentajedeerrorEditField_2Label = uilabel(app.HombroTab);
            app.PorcentajedeerrorEditField_2Label.Text = {'Porcentaje de error (%)'; ''};
            app.SealidealLamp_2Label = uilabel(app.HombroTab);
            app.SealidealLamp_2Label.Text = 'Señal ideal';
            app.SealidealLamp_2 = uilamp(app.HombroTab);
            app.SealrealizadaporelpacienteLamp_2Label = uilabel(app.HombroTab);
            app.SealrealizadaporelpacienteLamp_2Label.Text = 'Señal realizada por el paciente';
            app.SealrealizadaporelpacienteLamp_2 = uilamp(app.HombroTab);
            app.nguloInicialEditField_2Label = uilabel(app.HombroTab);
            app.nguloInicialEditField_2Label.Text = 'Ángulo Inicial(º)';
            app.nguloInicialEditField_2 = uieditfield(app.HombroTab, 'numeric');
            app.nguloFinalEditField_2Label = uilabel(app.HombroTab);
            app.nguloFinalEditField_2Label.Text = 'Ángulo Final (º)';
            app.nguloFinalEditField_2 = uieditfield(app.HombroTab, 'numeric');
            app.TiempoSubirsEditField_2Label = uilabel(app.HombroTab);
            app.TiempoSubirsEditField_2Label.Text = 'Tiempo Subir (s) ';
            app.TiempoSubirsEditField_2 = uieditfield(app.HombroTab, 'numeric');
            app.TiempoBajarsEditField_2Label = uilabel(app.HombroTab);
            app.TiempoBajarsEditField_2Label.Text = 'Tiempo Bajar (s) ';
            app.TiempoBajarsEditField_2 = uieditfield(app.HombroTab, 'numeric');
            app.TiempoTotalsEditField_2Label = uilabel(app.HombroTab);
            app.TiempoTotalsEditField_2Label.Text = 'Tiempo Total (s) ';
            app.TiempoTotalsEditField_2 = uieditfield(app.HombroTab, 'numeric');
            app.VelocidadAngularBajadaradsEditField_2Label = uilabel(app.HombroTab);
            app.VelocidadAngularBajadaradsEditField_2Label.Text = 'Velocidad Angular Bajada(rad/s) ';
            app.VelocidadAngularBajadaradsEditField_2 = uieditfield(app.HombroTab, 'numeric');
            app.VelocidadAngularSubidaradsEditField_2Label = uilabel(app.HombroTab);
            app.VelocidadAngularSubidaradsEditField_2Label.Text = 'Velocidad Angular Subida(rad/s) ';
            app.VelocidadAngularSubidaradsEditField_2 = uieditfield(app.HombroTab, 'numeric');
            app.nguloMaximoEditField_2Label = uilabel(app.HombroTab);
            app.nguloMaximoEditField_2Label.Text = 'Ángulo Maximo (º)';
            app.nguloMaximoEditField_2 = uieditfield(app.HombroTab, 'numeric');
            app.MuecaTab = uitab(app.TabGroup2);
            app.MuecaTab.Title = 'Muñeca';
            app.UIAxes_7 = uiaxes(app.MuecaTab);
            title(app.UIAxes_7, 'Comparación Gráfica Ideal')
            xlabel(app.UIAxes_7, 'Tiempo (s)')
            ylabel(app.UIAxes_7, {'Ángulo (º)'; ''})
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.MuecaTab);
            title(app.UIAxes_8, ' Señal Filtrada y Normalizada en Rangos')
            xlabel(app.UIAxes_8, 'Ciclo (%)')
            ylabel(app.UIAxes_8, 'Ángulo(º)')
            zlabel(app.UIAxes_8, 'Z')
            app.CiclosDetalladosButton_3 = uibutton(app.MuecaTab, 'push');
            app.CiclosDetalladosButton_3.ButtonPushedFcn = createCallbackFcn(app, @CiclosDetalladosButton_3Pushed, true);
            app.CiclosDetalladosButton_3.Text = 'Ciclos Detallados';
            app.MostrarSealesButton_3 = uibutton(app.MuecaTab, 'push');
            app.MostrarSealesButton_3.ButtonPushedFcn = createCallbackFcn(app, @MostrarSealesButton_3Pushed, true);
            app.MostrarSealesButton_3.Text = 'Mostrar Señales';
            app.AnalisisBiomecnicoButton_3 = uibutton(app.MuecaTab, 'push');
            app.AnalisisBiomecnicoButton_3.ButtonPushedFcn = createCallbackFcn(app, @AnalisisBiomecnicoButton_3Pushed, true);
            app.AnalisisBiomecnicoButton_3.Text = {'Analisis Biomecánico'; ''};
            app.AnlisisBiomecnicoLabel_3 = uilabel(app.MuecaTab);
            app.AnlisisBiomecnicoLabel_3.Text = 'Análisis Biomecánico';
            app.DiferenciadeTiemposEditField_3 = uieditfield(app.MuecaTab, 'numeric');
            app.DiferenciadeTiemposEditField_3.Editable = 'off';
            app.DiferenciadeTiemposEditField_3Label = uilabel(app.MuecaTab);
            app.DiferenciadeTiemposEditField_3Label.Text = {'Diferencia de Tiempo (s)'; ''};
            app.PorcentajedeerrorEditField_3 = uieditfield(app.MuecaTab, 'numeric');
            app.PorcentajedeerrorEditField_3.Editable = 'off';
            app.PorcentajedeerrorEditField_3Label = uilabel(app.MuecaTab);
            app.PorcentajedeerrorEditField_3Label.Text = {'Porcentaje de error (%)'; ''};
            app.SealidealLamp_3Label = uilabel(app.MuecaTab);
            app.SealidealLamp_3Label.Text = 'Señal ideal';
            app.SealidealLamp_3 = uilamp(app.MuecaTab);
            app.SealrealizadaporelpacienteLamp_3Label = uilabel(app.MuecaTab);
            app.SealrealizadaporelpacienteLamp_3Label.Text = 'Señal realizada por el paciente';
            app.SealrealizadaporelpacienteLamp_3 = uilamp(app.MuecaTab);
            app.nguloInicialEditField_3Label = uilabel(app.MuecaTab);
            app.nguloInicialEditField_3Label.Text = 'Ángulo Inicial(º)';
            app.nguloInicialEditField_3 = uieditfield(app.MuecaTab, 'numeric');
            app.nguloFinalEditField_3Label = uilabel(app.MuecaTab);
            app.nguloFinalEditField_3Label.Text = 'Ángulo Final (º)';
            app.nguloFinalEditField_3 = uieditfield(app.MuecaTab, 'numeric');
            app.TiempoSubirsEditField_3Label = uilabel(app.MuecaTab);
            app.TiempoSubirsEditField_3Label.Text = 'Tiempo Subir (s) ';
            app.TiempoSubirsEditField_3 = uieditfield(app.MuecaTab, 'numeric');
            app.TiempoBajarsEditField_3Label = uilabel(app.MuecaTab);
            app.TiempoBajarsEditField_3Label.Text = 'Tiempo Bajar (s) ';
            app.TiempoBajarsEditField_3 = uieditfield(app.MuecaTab, 'numeric');
            app.TiempoTotalsEditField_3Label = uilabel(app.MuecaTab);
            app.TiempoTotalsEditField_3Label.Text = 'Tiempo Total (s) ';
            app.TiempoTotalsEditField_3 = uieditfield(app.MuecaTab, 'numeric');
            app.VelocidadAngularBajadaradsEditField_3Label = uilabel(app.MuecaTab);
            app.VelocidadAngularBajadaradsEditField_3Label.Text = 'Velocidad Angular Bajada(rad/s) ';
            app.VelocidadAngularBajadaradsEditField_3 = uieditfield(app.MuecaTab, 'numeric');
            app.VelocidadAngularSubidaradsEditField_3Label = uilabel(app.MuecaTab);
            app.VelocidadAngularSubidaradsEditField_3Label.Text = 'Velocidad Angular Subida(rad/s) ';
            app.VelocidadAngularSubidaradsEditField_3 = uieditfield(app.MuecaTab, 'numeric');
            app.nguloMaximoEditField_3Label = uilabel(app.MuecaTab);
            app.nguloMaximoEditField_3Label.Text = 'Ángulo Maximo (º)';
            app.nguloMaximoEditField_3 = uieditfield(app.MuecaTab, 'numeric');
            app.SimulacionconelavatarTab = uitab(app.TabGroup);
            app.SimulacionconelavatarTab.Title = 'Simulacion con el avatar';
            app.Panel_2 = uipanel(app.SimulacionconelavatarTab);
            app.UIAxes_2 = uiaxes(app.Panel_2);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.IniciarButton = uibutton(app.Panel_2, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = {'Iniciar '; ''; ''; ''};
            app.PararButton = uibutton(app.Panel_2, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.SimulacinMovimientoIdealLabel = uilabel(app.Panel_2);
            app.SimulacinMovimientoIdealLabel.Text = {'Simulación Movimiento Ideal'; ''};
            app.ReiniciarButton = uibutton(app.Panel_2, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.Panel_3 = uipanel(app.SimulacionconelavatarTab);
            app.UIAxes2 = uiaxes(app.Panel_3);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.IniciarButton_2 = uibutton(app.Panel_3, 'push');
            app.IniciarButton_2.ButtonPushedFcn = createCallbackFcn(app, @IniciarButton_2Pushed, true);
            app.IniciarButton_2.Text = {'Iniciar '; ''; ''; ''};
            app.PararButton_2 = uibutton(app.Panel_3, 'push');
            app.PararButton_2.ButtonPushedFcn = createCallbackFcn(app, @PararButton_2Pushed, true);
            app.PararButton_2.Text = 'Parar';
            app.SimulacinMovimientoPacienteLabel = uilabel(app.Panel_3);
            app.SimulacinMovimientoPacienteLabel.Text = 'Simulación Movimiento Paciente';
            app.ReiniciarButton_2 = uibutton(app.Panel_3, 'push');
            app.ReiniciarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_2Pushed, true);
            app.ReiniciarButton_2.Text = 'Reiniciar';
            app.SimulacinAvatarLabel = uilabel(app.SimulacionconelavatarTab);
            app.SimulacinAvatarLabel.Text = {'Simulación Avatar'; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = informe_usuario(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end