classdef informe_usuario_flexion_rodilla < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InformeDelUsuarioTab            matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        FiltrarButton                   matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        PesoLabel                       matlab.ui.control.Label
        AlturaLabel                     matlab.ui.control.Label
        CausaLabel                      matlab.ui.control.Label
        DNILabel_2                      matlab.ui.control.Label
        ApellidosLabel                  matlab.ui.control.Label
        NombreLabel                     matlab.ui.control.Label
        VolverAlMenButton               matlab.ui.control.Button
        PESOLabel                       matlab.ui.control.Label
        ALTURALabel                     matlab.ui.control.Label
        CAUSADELAREHABILITACINLabel     matlab.ui.control.Label
        DNILabel                        matlab.ui.control.Label
        APELLIDOSLabel                  matlab.ui.control.Label
        NOMBRELabel                     matlab.ui.control.Label
        InformeDeUsuarioLabel           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        AnlisisBiomecnicoTab            matlab.ui.container.Tab
        nguloMaximoEditField            matlab.ui.control.NumericEditField
        nguloMaximoEditFieldLabel       matlab.ui.control.Label
        AnlisisBiomecnicoLabel          matlab.ui.control.Label
        AnalisisBiomecnicoButton        matlab.ui.control.Button
        VelocidadAngularSubidaradsEditField  matlab.ui.control.NumericEditField
        VelocidadAngularSubidaradsEditFieldLabel  matlab.ui.control.Label
        VelocidadAngularBajadaradsEditField  matlab.ui.control.NumericEditField
        VelocidadAngularBajadaradsEditFieldLabel  matlab.ui.control.Label
        TiempoTotalsEditField           matlab.ui.control.NumericEditField
        TiempoTotalsEditFieldLabel      matlab.ui.control.Label
        TiempoBajarsEditField           matlab.ui.control.NumericEditField
        TiempoBajarsEditFieldLabel      matlab.ui.control.Label
        TiempoSubirsEditField           matlab.ui.control.NumericEditField
        TiempoSubirsLabel               matlab.ui.control.Label
        nguloFinalEditField             matlab.ui.control.NumericEditField
        nguloFinalLabel                 matlab.ui.control.Label
        nguloInicialEditField           matlab.ui.control.NumericEditField
        nguloInicialLabel               matlab.ui.control.Label
        SealrealizadaporelpacienteLamp  matlab.ui.control.Lamp
        SealrealizadaporelpacienteLampLabel  matlab.ui.control.Label
        SealidealLamp                   matlab.ui.control.Lamp
        SealidealLampLabel              matlab.ui.control.Label
        PorcentajedeerrorEditFieldLabel  matlab.ui.control.Label
        PorcentajedeerrorEditField      matlab.ui.control.NumericEditField
        DiferenciadeTiemposEditFieldLabel  matlab.ui.control.Label
        DiferenciadeTiemposEditField    matlab.ui.control.NumericEditField
        MostrarSealesButton             matlab.ui.control.Button
        CiclosDetalladosButton          matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        SimulacionconelavatarTab        matlab.ui.container.Tab
        SimulacinAvatarLabel            matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        ReiniciarButton_2               matlab.ui.control.Button
        SimulacinMovimientoPacienteLabel  matlab.ui.control.Label
        PararButton_2                   matlab.ui.control.Button
        IniciarButton_2                 matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        ReiniciarButton                 matlab.ui.control.Button
        SimulacinMovimientoIdealLabel   matlab.ui.control.Label
        PararButton                     matlab.ui.control.Button
        IniciarButton                   matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property 
        pinta
        valor
         curva
        curva10k
        eje10s
        curvaVariable
        ejeAleatorioTiempo
        ejex
        ejex10k
        curvam10k
        curvanueva10k
        peso
        altura
        causa
        DNI
        apellidos
        nombre
        curvafiltrada
     introduccion_datos_del_paciente
       torso
        brazof
        brazo
        mano
        brazo2
        brazof2
        mano2
        pierna
        pierna2
        gemelo
        gemelo2
        pies
        pies2
        gradoDeCalidad
        f
        x
        y
        C
        curva10ksup
        curva10kinf
        tiempod
        AngInic
       AngFin
       AngMax;
       Tiempo1_2
       Tiempo2_3
       TiempoTotal
       VelocAng1_2
       VelocAng2_3
       pasopintado
       pinta2
       pasopintado2
     
    end


    methods (Access = private)
        
        function filtrar(app)
             
             fs=5000;
%Filtramos a 10Hz para no ser agresivos
wcfpb=10/(fs/2);
sincpb=wcfpb*sinc(wcfpb*(-3000:3000));

%Filtramos la señal
curvafR =conv(app.curvaVariable,sincpb);
tfcurvafR= fftshift(fft(curvafR));
app.curvafiltrada = ifft(ifftshift(tfcurvafR),'symmetric');
%Ajustamos el eje a 10000 muestras.
app.curvafiltrada=app.curvafiltrada(3001:end-3000);
              end
        
        
        function generarAvatar(app)
            v1= [10,30,0];
            v2= [70,30,0];
            v3=[70,10,0];
            v4=[10,10,0];
            v5=[10,30,80];
            v6=[70,30,80];
            v7=[70,10,80];
            v8=[10,10,80];
            %cargas del vertice
            app.torso= [v1;v2;v3;v4;v5;v6;v7;v8];
            %Cada cara esta formada por estos vertices
            app.f=[1,2,6,5; 2,3,7,6;3,4,8,7;1,4,8,5;1,2,3,4;5,6,7,8];
            %pintar las caras
            app.C= [0.30,0.75,0.93;0.30,0.75,0.93;0.30,0.75,0.93;0.30,0.75,0.93;1 1 1;1 1 1];
            %Un brazo
            b1=[70,30,30];
            b2=[80,30,30];
            b3=[80,10,30];
            b4=[70,10,30];
            b5=[70,30,80];
            b6=[80,30,80];
            b7=[80,10,80];
            b8=[70,10,80];
            app.brazo=[b1;b2;b3;b4;b5;b6;b7;b8];
            %c2= [0 1 1;0.5 0.5 0.6;0 0 1;1 1 0;1 0 1;1 1 1];

            %brazo femur
            app.brazof= [70,28,-10;80,28,-10;80,12,-10;70,12,-10;70,28,30;80,28,30;80,12,30;70,12,30];

            %mano
            app.mano= [70,25,-20;80,25,-20;80,15,-20;70,15,-20;70,25,-10;80,25,-10;80,12,-10;70,12,-10];

            app.mano2= [70,-25,-20;80,-25,-20;80,-15,-20;70,-15,-20;70,-25,-10;80,-25,-10;80,-12,-10;70,-12,-10];
            app.brazo2=[b1;b2;b3;b4;b5;b6;b7;b8];

            %CUERPO ENTERO
            app.brazo2= [0,10,80;0,10,30;0,30,30;0,30,80;10,10,80;10,10,30;10,30,30;10,30,80];
            app.brazof= [70,28,-10;80,28,-10;80,12,-10;70,12,-10;70,28,30;80,28,30;80,12,30;70,12,30];
            app.brazof2= [0,28,-10;10,28,-10;10,12,-10;0,12,-10;0,28,30;10,28,30;10,12,30;0,12,30];
            app.mano= [70,25,-20;80,25,-20;80,15,-20;70,15,-20;70,25,-10;80,25,-10;80,12,-10;70,12,-10];
            app.mano2= [0,25,-20;10,25,-20;10,15,-20;0,15,-20;0,25,-10;10,25,-10;10,12,-10;0,12,-10];
            v1= [10,30,0];
            v2= [70,30,0];
            v3=[70,10,0];
            v4=[10,10,0];
            v5=[10,30,80];
            v6=[70,30,80];
            v7=[70,10,80];
            v8=[10,10,80];
            app.pierna=[15,30,0;35,30,0;35,10,0;15,10,0;15,30,-40;35,30,-40;35,10,-40;15,10,-40];
            app.gemelo=[17,30,-40;32,30,-40;32,10,-40;17,10,-40;17,30,-90;32,30,-90;32,10,-90;17,10,-90];
            app.pierna2=[45,30,0;65,30,0;65,10,0;45,10,0;45,30,-40;65,30,-40;65,10,-40;45,10,-40];
               app.gemelo=[17,30,-40;32,30,-40;32,10,-40;17,10,-40;17,30,-90;32,30,-90;32,10,-90;17,10,-90];
            app.gemelo2=[47,30,-40;62,30,-40;62,10,-40;47,10,-40;47,30,-90;62,30,-90;62,10,-90;47,10,-90];
            app.pies=[17,30,-90;32,30,-90;32,-10,-90;17,-10,-90;17,30,-100;32,30,-100;32,-10,-100;17,-10,-100];
            app.pies2=[47,30,-90;62,30,-90;62,-10,-90;47,-10,-90;47,30,-100;62,30,-100;62,-10,-100;47,-10,-100];

        end
        function graficarMovIdeal(app)
            generarAvatar(app)


            function R = rotationMatrix(yaw,pitch,roll)
%UNTITLED2 Summary of this function goes here
%   Detailed explanation goes here
matrixYaw = [cosd(yaw),-sind(yaw),0;...
    sind(yaw),cosd(yaw),0;0,0,1];
matrixPitch = [cosd(pitch),0,sind(pitch);...
    0,1,0;-sind(pitch),0,cosd(pitch)];
matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);...
    0,sind(roll),cosd(roll)];
R = matrixYaw*matrixPitch*matrixRoll;
        end
        function modelRotated = applyRotationNx3(model,...
    displacement,R)
modelRotated = R*(model-repmat(displacement,size(model,1),1))';
modelRotated = modelRotated' + repmat(displacement,size(model,1),1);
end
           while app.pinta ==1
            mov=29;
           
            %plot(app.curvanueva10k);
            for i=app.pasopintado:floor(length(app.curvanueva10k)/mov):length(app.curvanueva10k)
                YPRtorso=[0,0,0];
                YPRhombro= [0,0,0];
                % Cojo la flexion que toca
                YPRcodo=[0,0,0];
                YPRwrist=[0,0,0];

                %Convertimos los vectores en una matriz rotación de cada elemento
                Rtorso = rotationMatrix(0,0,0);
                Rhombro = rotationMatrix(0,0,0);
                Rcodo = rotationMatrix(0,0, 0);
                Rwrist = rotationMatrix(0,0,0);
                    Rpiernacadera = rotationMatrix(0,0,-app.curvanueva10k(i));
                     Rcadera=rotationMatrix(0,0,0);
               Rtobillo = rotationMatrix(0,0,0);
               Rrodilla = rotationMatrix(0,0,0);
                %para definir el eje de rotacion
                manoRotada = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(app.mano,...
                    sum(app.brazof(1:4,:))/4,Rwrist),sum(app.brazo(1:4,:))/4,Rcodo),(app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %En el antebrazo tenemos tres rotaciones, la primera segun la rotacion del
                %codo despues del hombro despues la del torso
                antebrazoRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(app.brazof,...
                    sum(app.brazo(1:4,:))/4,Rcodo),(app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %Se aplican dos rotaciones, la primera se rota el brazo donde su centro de
                %rotacion está cogiendo el punto 5 y 6 de ese vector torso(la mitad),y
                %aplicale rotacion de hombro .
                %Como ese movimiento hace que tambien rote el torso, ponemos la rotacion
                %del torso sum(torso)/8
                brazoRotado = applyRotationNx3(applyRotationNx3(app.brazo,...
                    (app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %La función de rotacion se le aplica al torso con centro de rotacion
                %sum(torso)/8 (8 son las filas) y tenemos la matriz de rotacion
              %  torsoRotado = applyRotationNx3(app.torso,...
               %     sum(app.torso)/8,Rtorso);


tobilloRotado=applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(app.pies,sum(app.gemelo(1:4,:))/4,Rtobillo),sum(app.pierna(1:4,:))/4,Rrodilla),sum(app.torso(1:4,:))/4,Rpiernacadera),sum(app.torso(1:4,:))/4,Rcadera);
tobilloRotado2=applyRotationNx3(app.gemelo2,sum(app.pies2)/8,Rtobillo); 

piernagemeloRotado=applyRotationNx3(applyRotationNx3(applyRotationNx3(app.gemelo,sum(app.pierna(1:4,:))/4,Rrodilla),sum(app.torso(1:4,:))/4,Rpiernacadera),sum(app.torso(1:4,:))/4,Rcadera);

Piernarotcadera = applyRotationNx3(applyRotationNx3(app.pierna, sum(app.torso(1:4,:))/4,Rpiernacadera),sum(app.torso(1:4,:))/4,Rcadera);


torsoRotado=applyRotationNx3(app.torso,sum(app.torso(1:4,:))/4,Rcadera);

                %Pinto sobre la curva (hold on) el marcador en rojo que va tocando
                %subplot(ax(1)), hold on, plot(eje10k(i),curva10k(i),'ro','MarkerSize',4)
                %En el subplot de la derecha voy a pintar el modelo rotado
                %Selecciono el subplot
                %app.UIAxes(ax(2))
                %Y limpio lo que haya
                cla(app.UIAxes_2)
                %Y ahora pinto. Figura rotada
                  patch(app.UIAxes_2,'faces',app.f,'vertices',piernagemeloRotado,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',tobilloRotado,'FaceVertexCData', app.C, 'FaceColor','flat')
                 patch(app.UIAxes_2,'faces',app.f,'vertices',Piernarotcadera,'FaceVertexCData',app.C,'FaceColor','flat')

                patch(app.UIAxes_2,'faces',app.f,'vertices',app.torso,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.brazo,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.brazo2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.brazof2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.mano2,'FaceVertexCData', app.C, 'FaceColor','flat')
               % patch(app.UIAxes_2,'faces',app.f,'vertices',app.pierna,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.pierna2,'FaceVertexCData', app.C, 'FaceColor','flat')
               patch(app.UIAxes_2,'faces',app.f,'vertices',app.gemelo2,'FaceVertexCData', app.C, 'FaceColor','flat')
              %  patch(app.UIAxes_2,'faces',app.f,'vertices',app.gemelo,'FaceVertexCData', app.C, 'FaceColor','flat')
             %   patch(app.UIAxes_2,'faces',app.f,'vertices',app.pies,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',app.pies2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',torsoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',brazoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',antebrazoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes_2,'faces',app.f,'vertices',manoRotada,'FaceVertexCData',app.C,'FaceColor','flat')

                view(app.UIAxes_2,3)
                axis(app.UIAxes_2,'image')
                hold(app.UIAxes_2,'on')
                [x,y,z]= sphere;
                surf(app.UIAxes_2,25*x+40,25*y+20,25*z+105)
                %surf(x,y,z)
                drawnow
                pause(0.3)
                hold (app.UIAxes_2,'on')
                if app.pinta==0;
                    app.pasopintado=i;
                    break
                     elseif app.pinta==2;
                     app.pasopintado=1;
                     break
                end
                  app.pasopintado=1;
            end
           end
        end
        
        function graficarRandom(app)
            generarAvatar(app)
            
           function R = rotationMatrix(yaw,pitch,roll)
%UNTITLED2 Summary of this function goes here
%   Detailed explanation goes here
matrixYaw = [cosd(yaw),-sind(yaw),0;...
    sind(yaw),cosd(yaw),0;0,0,1];
matrixPitch = [cosd(pitch),0,sind(pitch);...
    0,1,0;-sind(pitch),0,cosd(pitch)];
matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);...
    0,sind(roll),cosd(roll)];
R = matrixYaw*matrixPitch*matrixRoll;
        end
        function modelRotated = applyRotationNx3(model,...
    displacement,R)
modelRotated = R*(model-repmat(displacement,size(model,1),1))';
modelRotated = modelRotated' + repmat(displacement,size(model,1),1);
        end
         while app.pinta2 ==1
            %Movimiento a pintar
            mov=29;
            %Creamos un subplot de dos columnas
            % ax(1)=subplot(1,2,1)
            % ax(2)=subplot(1,2,2)
            %En el primero pintsmos la curva
            % subplot(ax(1)), plot(eje10k,10000)
            %Recorremos la curva para ir pintando cada rotacion
            for i=app.pasopintado2:floor(length(app.curvafiltrada)/mov):length(app.curvafiltrada)
                YPRtorso=[0,0,0];
                YPRhombro= [0,0,0];
                % Cojo la flexion que toca
                YPRcodo=[0,0,0];
                YPRwrist=[0,0,0];
      Rpiernacadera = rotationMatrix(0,0,-app.curvafiltrada(i));
                     Rcadera=rotationMatrix(0,0,0);
               Rtobillo = rotationMatrix(0,0,0);
               Rrodilla = rotationMatrix(0,0,0);
                %Convertimos los vectores en una matriz rotación de cada elemento
                Rtorso = rotationMatrix(0,0,0);
                Rhombro = rotationMatrix(0,0,0);
                Rcodo = rotationMatrix(0,0, 0);
                Rwrist = rotationMatrix(0,0,0);
                %para definir el eje de rotacion
                manoRotada = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(app.mano,...
                    sum(app.brazof(1:4,:))/4,Rwrist),sum(app.brazo(1:4,:))/4,Rcodo),(app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %En el antebrazo tenemos tres rotaciones, la primera segun la rotacion del
                %codo despues del hombro despues la del torso
                antebrazoRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(app.brazof,...
                    sum(app.brazo(1:4,:))/4,Rcodo),(app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %Se aplican dos rotaciones, la primera se rota el brazo donde su centro de
                %rotacion está cogiendo el punto 5 y 6 de ese vector torso(la mitad),y
                %aplicale rotacion de hombro .
                %Como ese movimiento hace que tambien rote el torso, ponemos la rotacion
                %del torso sum(torso)/8
                brazoRotado = applyRotationNx3(applyRotationNx3(app.brazo,...
                    (app.torso(5,:)+app.torso(6,:))/2,Rhombro),sum(app.torso)/8,Rtorso);
                %La función de rotacion se le aplica al torso con centro de rotacion
                %sum(torso)/8 (8 son las filas) y tenemos la matriz de rotacion
%                 torsoRotado = applyRotationNx3(app.torso,...
%                     sum(app.torso)/8,Rtorso);


tobilloRotado=applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(app.pies,sum(app.gemelo(1:4,:))/4,Rtobillo),sum(app.pierna(1:4,:))/4,Rrodilla),sum(app.torso(1:4,:))/4,Rpiernacadera),sum(app.torso(1:4,:))/4,Rcadera);
tobilloRotado2=applyRotationNx3(app.gemelo2,sum(app.pies2)/8,Rtobillo); 

piernagemeloRotado=applyRotationNx3(applyRotationNx3(applyRotationNx3(app.gemelo,sum(app.pierna(1:4,:))/4,Rrodilla),sum(app.torso(1:4,:))/4,Rpiernacadera),sum(app.torso(1:4,:))/4,Rcadera);

Piernarotcadera = applyRotationNx3(applyRotationNx3(app.pierna, sum(app.torso(1:4,:))/4,Rpiernacadera),sum(app.torso(1:4,:))/4,Rcadera);


torsoRotado=applyRotationNx3(app.torso,sum(app.torso(1:4,:))/4,Rcadera);


                %Pinto sobre la curva (hold on) el marcador en rojo que va tocando
                %subplot(ax(1)), hold on, plot(eje10k(i),curva10k(i),'ro','MarkerSize',4)
                %En el subplot de la derecha voy a pintar el modelo rotado
                %Selecciono el subplot
                %Y limpio lo que haya
                cla(app.UIAxes2)
                %Y ahora pinto. Figura rotada
                  patch(app.UIAxes2,'faces',app.f,'vertices',piernagemeloRotado,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',tobilloRotado,'FaceVertexCData', app.C, 'FaceColor','flat')
                 patch(app.UIAxes2,'faces',app.f,'vertices',Piernarotcadera,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.torso,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.brazo,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.brazo2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.brazof2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.mano2,'FaceVertexCData', app.C, 'FaceColor','flat')
%                 patch(app.UIAxes2,'faces',app.f,'vertices',app.pierna,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.pierna2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.gemelo2,'FaceVertexCData', app.C, 'FaceColor','flat')
%                 patch(app.UIAxes2,'faces',app.f,'vertices',app.gemelo,'FaceVertexCData', app.C, 'FaceColor','flat')
%                 patch(app.UIAxes2,'faces',app.f,'vertices',app.pies,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',app.pies2,'FaceVertexCData', app.C, 'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',torsoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',brazoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',antebrazoRotado,'FaceVertexCData',app.C,'FaceColor','flat')
                patch(app.UIAxes2,'faces',app.f,'vertices',manoRotada,'FaceVertexCData',app.C,'FaceColor','flat')

                view(app.UIAxes2,3)
                axis(app.UIAxes2,'image')
                hold(app.UIAxes2,'on')
                [x,y,z]= sphere;
                surf(app.UIAxes2,25*x+40,25*y+20,25*z+105)
                %surf(x,y,z)
                drawnow
                pause(0.3)
                hold (app.UIAxes2,'on')
                 if app.pinta2==0
                    app.pasopintado2=i;
                    break
                 elseif app.pinta2==2
                     app.pasopintado2=1;
                     break
                 end
                  app.pasopintado2=1;
            end
         end
        end


        function analisisBiomec(app)
            %Ángulos
%x=app.NombreDeLaCurva; 
%y=x(1);
% DATO ÁNGULO
app.AngInic=app.curvafiltrada(1);
app.AngFin=app.curvafiltrada(end); % DATO ANGULO

%app.AngInic=30;
%app.AngFin=30;

MuestraAngInic=0;
[app.AngMax,MuestraAngMax]=max(app.curvafiltrada); %DATO ÁNGULO, el otro nos hace falta para calcular pero no hace falta mostrarlo
MuestraAngFin=length(app.curvafiltrada);

%Tiempos
muestras=10000;
t=app.tiempod+10;
fs=muestras/t;
 

app.Tiempo1_2=((MuestraAngMax-MuestraAngInic)/fs); %DATO TIEMPO
app.Tiempo2_3=((MuestraAngFin-MuestraAngMax)/fs); %DATO TIEMPO
app.TiempoTotal=((MuestraAngFin-MuestraAngInic)/fs); %DATO TIEMPO


%Velocidad angular. En radianes que es lo correcto
%Convertimos de grados a radianes
RadAngMax=((app.AngMax*pi)/180);
RadAngInic=((app.AngInic*pi)/180);
RadAngFin=((app.AngFin*pi)/180);
%Velocidad angular = w
app.VelocAng1_2=((RadAngMax-RadAngInic)/app.Tiempo1_2); %DATO VELOCIDAD ANGULAR
app.VelocAng2_3=((RadAngMax-RadAngFin)/app.Tiempo2_3); %DATO VELOCIDAD ANGULAR
   

         app.TiempoTotalsEditField.Value = app.tiempod+10;
          app.TiempoBajarsEditField.Value=app.Tiempo1_2;
          app.TiempoSubirsEditField.Value=app.Tiempo2_3;
          app.VelocidadAngularBajadaradsEditField.Value=app.VelocAng1_2;
          app.VelocidadAngularSubidaradsEditField.Value=app.VelocAng2_3;
          app.nguloFinalEditField.Value=app.AngFin;
          app.nguloInicialEditField.Value=app.AngInic;
          app.nguloMaximoEditField.Value=app.AngMax;
         app.DiferenciadeTiemposEditField.Value = double(app.tiempod);    


        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ejeAleatorioTiempo, curvaVariable, eje10s, curvanueva10k, curva10ksup, curva10kinf, peso, altura, causa, DNI, apellidos, nombre, tiempod)
        app.eje10s=eje10s;
        app.curvaVariable=curvaVariable;
        app.ejeAleatorioTiempo=ejeAleatorioTiempo;
        app.curvanueva10k=curvanueva10k;
        app.curva10ksup= curva10ksup;
        app.curva10kinf= curva10kinf;
        app.NombreLabel.Text=nombre;
        app.tiempod=tiempod;
    plot(app.UIAxes,app.ejeAleatorioTiempo,app.curvaVariable,'k');
        app.pinta=0;
        app.valor=0;
        app.pasopintado=1;
         app.pinta2=0;
          app.pasopintado2=1;
        
          
            app.ApellidosLabel.Text=apellidos;

            app.DNILabel_2.Text=DNI;

            app.CausaLabel.Text=causa;

            app.AlturaLabel.Text=num2str(altura);

            app.PesoLabel.Text=num2str(peso);
        end

        % Button pushed function: VolverAlMenButton
        function VolverAlMenButtonPushed(app, event)
            biosoft_menu_principal
            delete(app);
        end

        % Button pushed function: FiltrarButton
        function FiltrarButtonPushed(app, event)
 app.valor=~app.valor;
 switch app.valor
     case 1
         app.FiltrarButton.Text ='Señal Original';
            filtrar(app);
         plot(app.UIAxes,app.ejeAleatorioTiempo,app.curvafiltrada,'k');
     case 0
          app.FiltrarButton.Text ='Filtrar';
 plot(app.UIAxes,app.ejeAleatorioTiempo,app.curvaVariable,'k');
 end
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            app.pinta =1;
            graficarMovIdeal(app);
            
        end

        % Button pushed function: IniciarButton_2
        function IniciarButton_2Pushed(app, event)
             app.pinta2 =1;
            filtrar(app);
            graficarRandom(app);
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            app.pinta =0;
            graficarMovIdeal(app);
           
        end

        % Button pushed function: MostrarSealesButton
        function MostrarSealesButtonPushed(app, event)
           filtrar(app)
             plot(app.UIAxes_3,app.ejeAleatorioTiempo,app.curvafiltrada,'k');
             hold (app.UIAxes_3,'on');
             plot(app.UIAxes_3,app.ejeAleatorioTiempo,app.curvanueva10k,'r');
%           z=0;
%           y=0;
%           x=0;
%           i=0;
         
            
%       y = find(app.curvafiltrada>app.curva10ksup);
%           x = find(app.curvafiltrada<app.curva10kinf);
%            z=find(y>0);
%            k=z(end:end);
%            i=find(x>0);
%            m=i(end:end);
%            muestras=m+k;
%            app.gradoDeCalidad=((muestras*100)/length(app.curvafiltrada));    
    % app.PorcentajedeerrorEditField.Value = double(app.gradoDeCalidad);

        end

        % Button pushed function: CiclosDetalladosButton
        function CiclosDetalladosButtonPushed(app, event)
            filtrar(app)
            app.ejex= linspace(0,100,length(app.curvanueva10k));
            plot(app.UIAxes_4,app.ejex,app.curva10ksup,'b--');
            hold(app.UIAxes_4,'on');
            plot(app.UIAxes_4,app.ejex,app.curva10kinf,'b--');
            hold(app.UIAxes_4,'on');
            paso = 100;
                for i=1:paso:length(app.ejex)-paso-1 axis(app.UIAxes_4)
                plot(app.UIAxes_4,app.ejex(1:i+paso) ,app.curvafiltrada(1:i+paso),"g");
                pause(0.001)
                end
            hold(app.UIAxes_4,'off');

            drawnow;
        end

        % Button pushed function: PararButton_2
        function PararButton_2Pushed(app, event)
               app.pinta2 = 0;
              graficarRandom(app);
        end

        % Button pushed function: AnalisisBiomecnicoButton
        function AnalisisBiomecnicoButtonPushed(app, event)
           filtrar(app)
             plot(app.UIAxes_3,app.ejeAleatorioTiempo,app.curvafiltrada,'k');
             hold (app.UIAxes_3,'on');
             plot(app.UIAxes_3,app.ejeAleatorioTiempo,app.curvanueva10k,'r');
%           z=0;
%           y=0;
%           x=0;
%           i=0;
         analisisBiomec(app)
            
           y = find(app.curvafiltrada>app.curva10ksup);
           x = find(app.curvafiltrada<app.curva10kinf);
           z=find(y>0);
           k=length(z);
           i=find(x>0);
           m=length(i);
           muestras=m+k;
           app.gradoDeCalidad=((muestras*100)/length(app.curvafiltrada));
            
            app.PorcentajedeerrorEditField.Value = double(app.gradoDeCalidad);
      
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
           app.pinta=2;
           app.pasopintado=1;
           cla(app.UIAxes_2)
           graficarMovIdeal(app);
           app.pinta=1;
           graficarMovIdeal(app);
        end

        % Button pushed function: ReiniciarButton_2
        function ReiniciarButton_2Pushed(app, event)
            app.pinta2=2;
           app.pasopintado2=1;
           cla(app.UIAxes2)
           graficarRandom(app);
           app.pinta2=1;
           graficarRandom(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InformeDelUsuarioTab = uitab(app.TabGroup);
            app.InformeDelUsuarioTab.Title = 'Informe Del Usuario';
            app.UIAxes = uiaxes(app.InformeDelUsuarioTab);
            title(app.UIAxes, 'Ciclos De Movimiento')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Ángulo (º)')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.InformeDelUsuarioTab);
            app.InformeDeUsuarioLabel = uilabel(app.Panel);
            app.InformeDeUsuarioLabel.Text = {'Informe De Usuario'; ''; ''};
            app.NOMBRELabel = uilabel(app.Panel);
            app.NOMBRELabel.Text = 'NOMBRE:';
            app.APELLIDOSLabel = uilabel(app.Panel);
            app.APELLIDOSLabel.Text = 'APELLIDOS:';
            app.DNILabel = uilabel(app.Panel);
            app.DNILabel.Text = 'D.N.I:';
            app.CAUSADELAREHABILITACINLabel = uilabel(app.Panel);
            app.CAUSADELAREHABILITACINLabel.Text = 'CAUSA DE LA REHABILITACIÓN:';
            app.ALTURALabel = uilabel(app.Panel);
            app.ALTURALabel.Text = 'ALTURA:';
            app.PESOLabel = uilabel(app.Panel);
            app.PESOLabel.Text = 'PESO:';
            app.VolverAlMenButton = uibutton(app.Panel, 'push');
            app.VolverAlMenButton.ButtonPushedFcn = createCallbackFcn(app, @VolverAlMenButtonPushed, true);
            app.VolverAlMenButton.Text = 'Volver Al Menú';
            app.NombreLabel = uilabel(app.Panel);
            app.NombreLabel.Text = {'Nombre'; ''; ''};
            app.ApellidosLabel = uilabel(app.Panel);
            app.ApellidosLabel.Text = 'Apellidos';
            app.DNILabel_2 = uilabel(app.Panel);
            app.DNILabel_2.Text = 'DNI';
            app.CausaLabel = uilabel(app.Panel);
            app.CausaLabel.Text = {'Causa'; ''};
            app.AlturaLabel = uilabel(app.Panel);
            app.AlturaLabel.Text = 'Altura';
            app.PesoLabel = uilabel(app.Panel);
            app.PesoLabel.Text = {'Peso'; ''};
            app.FiltrarButton = uibutton(app.InformeDelUsuarioTab, 'push');
            app.FiltrarButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarButtonPushed, true);
            app.FiltrarButton.Text = 'Filtrar';
            app.Image = uiimage(app.InformeDelUsuarioTab);
            app.Image.ImageSource = 'Captura de pantalla 2021-03-11 092442.png';
            app.AnlisisBiomecnicoTab = uitab(app.TabGroup);
            app.AnlisisBiomecnicoTab.Title = 'Análisis Biomecánico';
            app.UIAxes_3 = uiaxes(app.AnlisisBiomecnicoTab);
            title(app.UIAxes_3, 'Comparación Gráfica Ideal')
            xlabel(app.UIAxes_3, 'Tiempo (s)')
            ylabel(app.UIAxes_3, {'Ángulo (º)'; ''})
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.AnlisisBiomecnicoTab);
            title(app.UIAxes_4, ' Señal Filtrada y Normalizada en Rangos')
            xlabel(app.UIAxes_4, 'Ciclo (%)')
            ylabel(app.UIAxes_4, 'Ángulo(º)')
            zlabel(app.UIAxes_4, 'Z')
            app.CiclosDetalladosButton = uibutton(app.AnlisisBiomecnicoTab, 'push');
            app.CiclosDetalladosButton.ButtonPushedFcn = createCallbackFcn(app, @CiclosDetalladosButtonPushed, true);
            app.CiclosDetalladosButton.Text = 'Ciclos Detallados';
            app.MostrarSealesButton = uibutton(app.AnlisisBiomecnicoTab, 'push');
            app.MostrarSealesButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarSealesButtonPushed, true);
            app.MostrarSealesButton.Text = 'Mostrar Señales';
            app.DiferenciadeTiemposEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.DiferenciadeTiemposEditField.Editable = 'off';
            app.DiferenciadeTiemposEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.DiferenciadeTiemposEditFieldLabel.Text = {'Diferencia de Tiempo (s)'; ''};
            app.PorcentajedeerrorEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.PorcentajedeerrorEditField.Editable = 'off';
            app.PorcentajedeerrorEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.PorcentajedeerrorEditFieldLabel.Text = {'Porcentaje de error (%)'; ''};
            app.SealidealLampLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.SealidealLampLabel.Text = 'Señal ideal';
            app.SealidealLamp = uilamp(app.AnlisisBiomecnicoTab);
            app.SealrealizadaporelpacienteLampLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.SealrealizadaporelpacienteLampLabel.Text = 'Señal realizada por el paciente';
            app.SealrealizadaporelpacienteLamp = uilamp(app.AnlisisBiomecnicoTab);
            app.nguloInicialLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.nguloInicialLabel.Text = 'Ángulo Inicial(º)';
            app.nguloInicialEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.nguloFinalLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.nguloFinalLabel.Text = 'Ángulo Final (º)';
            app.nguloFinalEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.TiempoSubirsLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.TiempoSubirsLabel.Text = 'Tiempo Subir (s) ';
            app.TiempoSubirsEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.TiempoBajarsEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.TiempoBajarsEditFieldLabel.Text = 'Tiempo Bajar (s) ';
            app.TiempoBajarsEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.TiempoTotalsEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.TiempoTotalsEditFieldLabel.Text = 'Tiempo Total (s) ';
            app.TiempoTotalsEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.VelocidadAngularBajadaradsEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.VelocidadAngularBajadaradsEditFieldLabel.Text = 'Velocidad Angular Bajada(rad/s) ';
            app.VelocidadAngularBajadaradsEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.VelocidadAngularSubidaradsEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.VelocidadAngularSubidaradsEditFieldLabel.Text = 'Velocidad Angular Subida(rad/s) ';
            app.VelocidadAngularSubidaradsEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.AnalisisBiomecnicoButton = uibutton(app.AnlisisBiomecnicoTab, 'push');
            app.AnalisisBiomecnicoButton.ButtonPushedFcn = createCallbackFcn(app, @AnalisisBiomecnicoButtonPushed, true);
            app.AnalisisBiomecnicoButton.Text = {'Analisis Biomecánico'; ''};
            app.AnlisisBiomecnicoLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.AnlisisBiomecnicoLabel.Text = 'Análisis Biomecánico';
            app.nguloMaximoEditFieldLabel = uilabel(app.AnlisisBiomecnicoTab);
            app.nguloMaximoEditFieldLabel.Text = 'Ángulo Maximo (º)';
            app.nguloMaximoEditField = uieditfield(app.AnlisisBiomecnicoTab, 'numeric');
            app.SimulacionconelavatarTab = uitab(app.TabGroup);
            app.SimulacionconelavatarTab.Title = 'Simulacion con el avatar';
            app.Panel_2 = uipanel(app.SimulacionconelavatarTab);
            app.UIAxes_2 = uiaxes(app.Panel_2);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.IniciarButton = uibutton(app.Panel_2, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = {'Iniciar '; ''; ''; ''};
            app.PararButton = uibutton(app.Panel_2, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.SimulacinMovimientoIdealLabel = uilabel(app.Panel_2);
            app.SimulacinMovimientoIdealLabel.Text = {'Simulación Movimiento Ideal'; ''};
            app.ReiniciarButton = uibutton(app.Panel_2, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.Panel_3 = uipanel(app.SimulacionconelavatarTab);
            app.UIAxes2 = uiaxes(app.Panel_3);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.IniciarButton_2 = uibutton(app.Panel_3, 'push');
            app.IniciarButton_2.ButtonPushedFcn = createCallbackFcn(app, @IniciarButton_2Pushed, true);
            app.IniciarButton_2.Text = {'Iniciar '; ''; ''; ''};
            app.PararButton_2 = uibutton(app.Panel_3, 'push');
            app.PararButton_2.ButtonPushedFcn = createCallbackFcn(app, @PararButton_2Pushed, true);
            app.PararButton_2.Text = 'Parar';
            app.SimulacinMovimientoPacienteLabel = uilabel(app.Panel_3);
            app.SimulacinMovimientoPacienteLabel.Text = 'Simulación Movimiento Paciente';
            app.ReiniciarButton_2 = uibutton(app.Panel_3, 'push');
            app.ReiniciarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_2Pushed, true);
            app.ReiniciarButton_2.Text = 'Reiniciar';
            app.SimulacinAvatarLabel = uilabel(app.SimulacionconelavatarTab);
            app.SimulacinAvatarLabel.Text = {'Simulación Avatar'; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = informe_usuario_flexion_rodilla(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end