classdef introduccion_datos_del_paciente_flexion_rodilla < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        IniciarButton            matlab.ui.control.Button
        PesoSpinner              matlab.ui.control.Spinner
        PesoSpinnerLabel         matlab.ui.control.Label
        AlturaSpinner            matlab.ui.control.Spinner
        AlturaSpinnerLabel       matlab.ui.control.Label
        CausaDelaRehabilitacinEditField  matlab.ui.control.EditField
        CausaDelaRehabilitacinEditFieldLabel  matlab.ui.control.Label
        DNIEditField             matlab.ui.control.EditField
        DNIEditFieldLabel        matlab.ui.control.Label
        ApellidosEditField       matlab.ui.control.EditField
        ApellidosEditFieldLabel  matlab.ui.control.Label
        PorfavorintroduzcalossiguientesdatosLabel  matlab.ui.control.Label
        NombreEditField          matlab.ui.control.EditField
        NombreEditFieldLabel     matlab.ui.control.Label
        DatosdelUsuarioLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        curva
        curva10k
        eje10s
        curvaVariable
        ejeAleatorioTiempo
        ejex
        ejex10k
        curvam10k
        curvanueva10k
        peso
        altura
        causa
        DNI
        apellidos
        nombre
        curva10ksup
        curva10kinf
        tiempod
       

    end
    
    methods (Access = private)
        
        function graficaideal(app)
            app.curva = [0,10,30,50,70,80,85,87,89,90,89,87,85,80,70,50,30,10,0]
app.ejex= linspace(0,100,length(app.curva))
%Suavizamos la grafica interpolando. Creamos nuevo ejex cogiendo el
%anterior pero le damos más resolucion entre los valores de 0 a 100
app.ejex10k=linspace(0,100,10000)
app.curvam10k= makima(app.ejex,app.curva,app.ejex10k)
%hacerlo mas preciso
pp = makima(app.ejex,app.curva)
app.curva10k=ppval(pp,linspace(0,100,10000))
app.eje10s= linspace(0,10,length(app.curva10k))
 app.curva10ksup=app.curva10k + 5;
            app.curva10kinf=app.curva10k - 5;
        end
        
        function aleatoriaconruido(app)
            graficaideal(app)
            %VARIABILIDAD EN LA AMPLITUD.
rango= max(app.curva10k)-min(app.curva10k)
%Rango hacia abajo y arriba un 5% (10%)
var=rango/10
r=rand(1,length(app.curva10k))


%amplitud
%Centrar en torno a 0
c = app.curva-mean(app.curva)
%estiramiento 10% hacia arriba 1.1
%estiramiento 10% hacia abajo a 0.9

 rr=0.9 + (1.1-0.9)*rand(1)
 rr2=0.7 + (1.3-0.7)*rand(1)
 
cnueva =[c(1:19)*rr c(20:end)*rr2]+mean(app.curva);
            %Aquí indicamos que el primer y último valor de la curva nueva es igual a
            %la de la original.
            cnueva(1)=app.curva(1);
            cnueva(end)=app.curva(end);
            %Aplico makima y me genera la curva con los 54 valores.
            ppn=makima(app.ejex,cnueva);
            app.curvanueva10k=ppval(ppn,linspace(0,100,10000));
            

%CURVA CON RUIDO
 al = var*r-(var/2);
%ruido
app.curvaVariable= al+app.curvanueva10k;

%vector de valores aleatorios
app.curvaVariable(1)=app.curva(1);
app.curvaVariable(end)=app.curva(end);
minimo=10;
maximo= 15;
duracion = minimo+(maximo-minimo)*rand(1);
app.ejeAleatorioTiempo=linspace(0,duracion,length(app.curvaVariable));
app.tiempod = duracion-minimo;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            graficaideal(app)
            aleatoriaconruido(app)
            %importdata(app.callingapp,)
            nombre=app.NombreEditField.Value;

            apellidos=app.ApellidosEditField.Value;

            DNI=app.DNIEditField.Value;

            causa=app.CausaDelaRehabilitacinEditField.Value;

            altura=app.AlturaSpinner.Value;

            peso=app.PesoSpinner.Value;

            TF = isempty(app.NombreEditField.Value);
             TF1 = isempty(app.ApellidosEditField.Value);
             TF2= isempty(app.DNIEditField.Value);
              TF3 = isempty(app.CausaDelaRehabilitacinEditField.Value);
              
            if TF==0 & TF1==0 & TF2==0 & TF3==0 
                if altura >0 & peso>0
           informe_usuario_flexion_rodilla(app.ejeAleatorioTiempo,app.curvaVariable, app.eje10s,app.curva10k,app.curva10ksup, app.curva10kinf,peso,altura,causa,DNI,apellidos,nombre,app.tiempod);
           delete(app)
                end
            else
                VentanaError
            
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatosdelUsuarioLabel = uilabel(app.UIFigure);
            app.DatosdelUsuarioLabel.Text = {'Datos del Usuario'; ''; ''};
            app.NombreEditFieldLabel = uilabel(app.UIFigure);
            app.NombreEditFieldLabel.Text = {'Nombre'; ''};
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.NombreEditField.Value = 'd';
            app.PorfavorintroduzcalossiguientesdatosLabel = uilabel(app.UIFigure);
            app.PorfavorintroduzcalossiguientesdatosLabel.Text = {'Por favor, introduzca los siguientes datos:'; ''};
            app.ApellidosEditFieldLabel = uilabel(app.UIFigure);
            app.ApellidosEditFieldLabel.Text = {'Apellidos'; ''};
            app.ApellidosEditField = uieditfield(app.UIFigure, 'text');
            app.ApellidosEditField.Value = 'd';
            app.DNIEditFieldLabel = uilabel(app.UIFigure);
            app.DNIEditFieldLabel.Text = {'D.N.I'; ''};
            app.DNIEditField = uieditfield(app.UIFigure, 'text');
            app.DNIEditField.Value = '5';
            app.CausaDelaRehabilitacinEditFieldLabel = uilabel(app.UIFigure);
            app.CausaDelaRehabilitacinEditFieldLabel.Text = {'Causa De la Rehabilitación'; ''};
            app.CausaDelaRehabilitacinEditField = uieditfield(app.UIFigure, 'text');
            app.CausaDelaRehabilitacinEditField.Value = 'dff';
            app.AlturaSpinnerLabel = uilabel(app.UIFigure);
            app.AlturaSpinnerLabel.Text = {'Altura'; ''};
            app.AlturaSpinner = uispinner(app.UIFigure);
            app.AlturaSpinner.Value = 5;
            app.PesoSpinnerLabel = uilabel(app.UIFigure);
            app.PesoSpinnerLabel.Text = {'Peso'; ''; ''};
            app.PesoSpinner = uispinner(app.UIFigure);
            app.PesoSpinner.Value = 5;
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = {'Iniciar '; ''; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = introduccion_datos_del_paciente_flexion_rodilla

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end