classdef introduccion_datos_del_paciente_sit_stand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PesoSpinner              matlab.ui.control.Spinner
        PesoSpinnerLabel         matlab.ui.control.Label
        AlturaSpinner            matlab.ui.control.Spinner
        AlturaSpinnerLabel       matlab.ui.control.Label
        CausaDelaRehabilitacinEditField  matlab.ui.control.EditField
        CausaDelaRehabilitacinEditFieldLabel  matlab.ui.control.Label
        DNIEditField             matlab.ui.control.EditField
        DNIEditFieldLabel        matlab.ui.control.Label
        ApellidosEditField       matlab.ui.control.EditField
        ApellidosEditFieldLabel  matlab.ui.control.Label
        NombreEditField          matlab.ui.control.EditField
        NombreEditFieldLabel     matlab.ui.control.Label
        IniciarButton            matlab.ui.control.Button
        PorfavorintroduzcalossiguientesdatosLabel  matlab.ui.control.Label
        DatosdelUsuarioLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
         curvaknee
        curva10krodilla
        eje10sR
        curvaVariableR
        ejeAleatorioTiempoR
        duracionR
        ejexRodilla
        ejex10kR
        curvam10k
        curvanueva10k
         curvahip
        curva10kcadera
        eje10sC
        curvafiltradaC
        ejexCadera
        ejex10k
        peso
        altura
        causa
        DNI
        apellidos
        nombre
        curva10ksupR
        curva10kinfR
        tiempod
    end
    
    methods (Access = private)
        
        function curvaidealKnee(app)
           %CURVA KNEE
app.curvaknee = [0 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 138 140 140 138 135 130 125 120 115 110 105 100 95 90 85 80 75 70 65 60 55 50 45 40 35 30 25 20 15 10 0];
app.ejexRodilla= linspace(0,100,length(app.curvaknee));
app.ejex10kR=linspace(0,100,10000);
%hacerlo mas preciso
pp = makima(app.ejexRodilla,app.curvaknee);
app.curva10krodilla=ppval(pp,linspace(0,100,10000));

 
        end
        
        function graficarandomknee(app)
          %VARIABILIDAD EN LA AMPLITUD.
rangoRodilla= max(app.curva10krodilla)-min(app.curva10krodilla);
%Rango hacia abajo y arriba un 5% (10%)
varRodilla=rangoRodilla/10;
r=rand(1,length(app.curva10krodilla));

%amplitud
%Centrar en torno a 0
cR = app.curvaknee-mean(app.curvaknee);
%estiramiento 10% hacia arriba 1.1
%estiramiento 10% hacia abajo a 0.9

 rr=0.9 + (1.1-0.9)*rand(1);
 rr2=0.7 + (1.3-0.7)*rand(1);
 
cnuevaR =[cR(1:15)*rr cR(16:end)*rr2]+mean(app.curvaknee);
            %Aquí indicamos que el primer y último valor de la curva nueva es igual a
            %la de la original.
            cnuevaR(1)=app.curvaknee(1);
            cnuevaR(end)=app.curvaknee(end);
            %Aplico makima y me genera la curva con los 54 valores.
            ppn=makima(app.ejexRodilla,cnuevaR);
            curvanueva10kR=ppval(ppn,linspace(0,100,10000));
            %Pinto la señal aleatoria
%             figure, plot(appejex10kR,curvanueva10kR,ejex10kR,app.curva10krodilla,'r')
%             title('Señal aleatoria')

%CURVA CON RUIDO
 alR = varRodilla*r-(varRodilla/2);
%ruido
app.curvaVariableR= alR+curvanueva10kR;
% figure, plot(ejex10kR,curvaVariableR)  
app.curvaVariableR(1)=app.curvaknee(1);
app.curvaVariableR(end)=app.curvaknee(end);
app.eje10sR= linspace(0,10,length(app.curva10krodilla));
% plot(eje10sR,curva10krodilla);
%vector de valores aleatorios
minimoR=6;
maximoR= 15;
app.duracionR = minimoR+(maximoR-minimoR)*rand(1);
app.ejeAleatorioTiempoR=linspace(0,app.duracionR,length(curvafiltradaR));
        end

        
        function curvaidealHip(app)
            app.curvahip = [2 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 125 120 115 110 105 100 95 90 85 80 75 70 65 60 55 50 45 40 35 30 25 20 15 10 2];
app.ejexCadera= linspace(0,100,length(app.curvahip));
app.ejex10kC=linspace(0,100,10000);
%hacerlo mas preciso
pp = makima(app.ejexCadera,app.curvahip);
app.curva10kcadera=ppval(pp,linspace(0,100,10000));

        end
        
        function graficarandomhip(app)
         %VARIABILIDAD EN LA AMPLITUD.
rangoCadera= max(app.curva10kcadera)-min(app.curva10kcadera);
%Rango hacia abajo y arriba un 5% (10%)
varCadera=rangoCadera/10;
r=rand(1,length(app.curva10kcadera))

%amplitud
%Centrar en torno a 0
cC = app.curvahip-mean(app.curvahip);
%estiramiento 10% hacia arriba 1.1
%estiramiento 10% hacia abajo a 0.9

 rr=0.9 + (1.1-0.9)*rand(1);
 rr2=0.7 + (1.3-0.7)*rand(1);
 
cnuevaC =[cC(1:26)*rr cC(27:end)*rr2]+mean(app.curvahip);
            %Aquí indicamos que el primer y último valor de la curva nueva es igual a
            %la de la original.
            cnuevaC(1)=app.curvahip(1);
            cnuevaC(end)=app.curvahip(end);
            %Aplico makima y me genera la curva con los 54 valores.
            ppn=makima(app.ejexCadera,cnuevaC);
            app.curvanueva10kC=ppval(ppn,linspace(0,100,10000));
            %Pinto la señal aleatoria
            figure, plot(ejex10kC,curvanueva10kC,ejex10kC,app.curva10kcadera,'r')
            title('Señal aleatoria')

%CURVA CON RUIDO
 alC = varCadera*r-(varCadera/2);
%ruido
app.curvaVariableC= alC+curvanueva10kC;
figure, plot(ejex10kC,app.curvaVariableC)


%TIEMPO ALEATORIO
app.curvafiltradaC(1)=app.curvahip(1);
app.curvafiltradaC(end)=app.curvahip(end);
figure,plot(app.eje10sR,app.curva10kcadera), hold on, plot(app.ejeAleatorioTiempoR,app.curvafiltradaC)   
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)

            curvaidealKnee(app)
            curvaidealHip(app)
            curvaidealAnkle(app)
            graficarandomknee(app)
            graficarandomhip(app)
             graficarandomhip(app)


            %importdata(app.callingapp,)
            app.nombre=app.NombreEditField.Value;

            app.apellidos=app.ApellidosEditField.Value;

            app.DNI=app.DNIEditField.Value;

            app.causa=app.CausaDelaRehabilitacinEditField.Value;

            app.altura=app.AlturaSpinner.Value;

            app.peso=app.PesoSpinner.Value;

            TF = isempty(app.NombreEditField.Value);
             TF1 = isempty(app.ApellidosEditField.Value);
             TF2= isempty(app.DNIEditField.Value);
              TF3 = isempty(app.CausaDelaRehabilitacinEditField.Value);
              
            if TF==0 & TF1==0 & TF2==0 & TF3==0 
                if app.altura >0 & app.peso>0
           informe_usuario_sit_stand(app.ejeAleatorioTiempoR, app.eje10sR,app.curvaVariableR,app.curva10krodilla,app.curva10ksupR, app.curva10kinfR,app.curvaVariableC,app.curva10kcadera,app.curva10ksupC, app.curva10kinfC,app.peso,app.altura,app.causa,app.DNI,app.apellidos,app.nombre,app.tiempod);
           delete(app)
                end
            else
                VentanaError
            
            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatosdelUsuarioLabel = uilabel(app.UIFigure);
            app.DatosdelUsuarioLabel.Text = {'Datos del Usuario'; ''; ''};
            app.PorfavorintroduzcalossiguientesdatosLabel = uilabel(app.UIFigure);
            app.PorfavorintroduzcalossiguientesdatosLabel.Text = {'Por favor, introduzca los siguientes datos:'; ''};
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = {'Iniciar '; ''; ''; ''};
            app.NombreEditFieldLabel = uilabel(app.UIFigure);
            app.NombreEditFieldLabel.Text = {'Nombre'; ''};
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.ApellidosEditFieldLabel = uilabel(app.UIFigure);
            app.ApellidosEditFieldLabel.Text = {'Apellidos'; ''};
            app.ApellidosEditField = uieditfield(app.UIFigure, 'text');
            app.DNIEditFieldLabel = uilabel(app.UIFigure);
            app.DNIEditFieldLabel.Text = {'D.N.I'; ''};
            app.DNIEditField = uieditfield(app.UIFigure, 'text');
            app.CausaDelaRehabilitacinEditFieldLabel = uilabel(app.UIFigure);
            app.CausaDelaRehabilitacinEditFieldLabel.Text = {'Causa De la Rehabilitación'; ''};
            app.CausaDelaRehabilitacinEditField = uieditfield(app.UIFigure, 'text');
            app.AlturaSpinnerLabel = uilabel(app.UIFigure);
            app.AlturaSpinnerLabel.Text = {'Altura'; ''};
            app.AlturaSpinner = uispinner(app.UIFigure);
            app.PesoSpinnerLabel = uilabel(app.UIFigure);
            app.PesoSpinnerLabel.Text = {'Peso'; ''; ''};
            app.PesoSpinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = introduccion_datos_del_paciente_sit_stand

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end